classdef PedroSimApp_M2021a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainPanel                       matlab.ui.container.Panel
        MainTabGroup                    matlab.ui.container.TabGroup
        VariabilityAnalysisTab          matlab.ui.container.Tab
        VReadEditField                  matlab.ui.control.NumericEditField
        VReadEditFieldLabel             matlab.ui.control.Label
        RRAMDataFileLoadButton          matlab.ui.control.Button
        RRAMDataFileBrowseButton        matlab.ui.control.Button
        RRAMDataFileEditField           matlab.ui.control.EditField
        RRAMDataFileEditFieldLabel      matlab.ui.control.Label
        VariabilityPlotsPanel           matlab.ui.container.Panel
        PlotAllButton                   matlab.ui.control.Button
        VariabilityPlotsTabGroup        matlab.ui.container.TabGroup
        RawDataTab                      matlab.ui.container.Tab
        RawDataPlotButton               matlab.ui.control.Button
        RawDataUIAxes                   matlab.ui.control.UIAxes
        ModelTab                        matlab.ui.container.Tab
        ModelPlotButton                 matlab.ui.control.Button
        ModelUIAxes                     matlab.ui.control.UIAxes
        D2DTab                          matlab.ui.container.Tab
        D2DTabGroup                     matlab.ui.container.TabGroup
        D2DStatFitTab                   matlab.ui.container.Tab
        D2DStatFitPlotButton            matlab.ui.control.Button
        D2DStatFitUIAxes                matlab.ui.control.UIAxes
        D2DNormWeightFitTab             matlab.ui.container.Tab
        D2DNormWeightFitPlotButton      matlab.ui.control.Button
        D2DNormWeightFitUIAxesParamB    matlab.ui.control.UIAxes
        D2DNormWeightFitUIAxesParamA    matlab.ui.control.UIAxes
        C2CTab                          matlab.ui.container.Tab
        C2CTabGroup                     matlab.ui.container.TabGroup
        C2CStatFitTab                   matlab.ui.container.Tab
        C2CStatFitPlotDropDown          matlab.ui.control.DropDown
        C2CNormalizedWeightPlotValueEditField  matlab.ui.control.NumericEditField
        C2CNormalizedWeightPlotValueEditFieldLabel  matlab.ui.control.Label
        C2CStatFitPlotButton            matlab.ui.control.Button
        C2CStatFitUIAxesRESET           matlab.ui.control.UIAxes
        C2CStatFitUIAxesSET             matlab.ui.control.UIAxes
        C2CNormWeightFitTab             matlab.ui.container.Tab
        C2CNormWeightPlotButton         matlab.ui.control.Button
        C2CNormWeightUIAxesRESETParamB  matlab.ui.control.UIAxes
        C2CNormWeightUIAxesSETParamB    matlab.ui.control.UIAxes
        C2CNormWeightUIAxesRESETParamA  matlab.ui.control.UIAxes
        C2CNormWeightUIAxesSETParamA    matlab.ui.control.UIAxes
        VariabilityanalysisPanel        matlab.ui.container.Panel
        RefCycleEditField               matlab.ui.control.NumericEditField
        RefCycleEditFieldLabel          matlab.ui.control.Label
        RefCycleDropDown                matlab.ui.control.DropDown
        RefCycleDropDownLabel           matlab.ui.control.Label
        NormLowerRefLimitDropDown       matlab.ui.control.DropDown
        NormLowerRefLimitDropDownLabel  matlab.ui.control.Label
        NormUpperRefLimitDropDown       matlab.ui.control.DropDown
        NormUpperRefLimitDropDownLabel  matlab.ui.control.Label
        NormalizeDropDown               matlab.ui.control.DropDown
        NormalizeDropDownLabel          matlab.ui.control.Label
        WeightConversionDropDown        matlab.ui.control.DropDown
        WeightConversionLabel           matlab.ui.control.Label
        VariabilityInputTabGroup        matlab.ui.container.TabGroup
        DiscretizationTab               matlab.ui.container.Tab
        DiscretizationFitHelp           matlab.ui.control.Image
        DiscretizationExportappOptionsCheckBox  matlab.ui.control.CheckBox
        DiscretizationExportStructButton  matlab.ui.control.Button
        DiscretizationModelSETEditField  matlab.ui.control.EditField
        DiscretizationModelSETEditFieldLabel  matlab.ui.control.Label
        DiscretizationModelRESETEditField  matlab.ui.control.EditField
        DiscretizationModelRESETEditFieldLabel  matlab.ui.control.Label
        ModelLabel2                     matlab.ui.control.Label
        ModelLabel                      matlab.ui.control.Label
        DiscretizationCheckBox          matlab.ui.control.CheckBox
        D2DVariabilityTab               matlab.ui.container.Tab
        D2DExportappOptionsCheckBox     matlab.ui.control.CheckBox
        D2DExportStructButton           matlab.ui.control.Button
        D2DNoteLabel                    matlab.ui.control.Label
        D2DsigmaEditField               matlab.ui.control.NumericEditField
        D2DsigmaEditFieldLabel          matlab.ui.control.Label
        D2DmuEditField                  matlab.ui.control.NumericEditField
        D2DmuEditFieldLabel             matlab.ui.control.Label
        D2DDistributionDropDown         matlab.ui.control.DropDown
        D2DDistributionDropDownLabel    matlab.ui.control.Label
        D2DVariabilityCheckBox          matlab.ui.control.CheckBox
        C2CVariabilityTab               matlab.ui.container.Tab
        C2CsigmaRESETEditField          matlab.ui.control.NumericEditField
        C2CsigmaRESETEditFieldLabel     matlab.ui.control.Label
        C2CmuRESETEditField             matlab.ui.control.NumericEditField
        C2CmuRESETEditFieldLabel        matlab.ui.control.Label
        C2CExportappOptionsCheckBox     matlab.ui.control.CheckBox
        C2CExportStructButton           matlab.ui.control.Button
        C2CFitModelHelpImage            matlab.ui.control.Image
        C2CFitNumberofPointsEditField   matlab.ui.control.NumericEditField
        C2CFitNumberofPointsEditFieldLabel  matlab.ui.control.Label
        C2CFitModelEditField            matlab.ui.control.EditField
        C2CFitModelEditFieldLabel       matlab.ui.control.Label
        C2CSpacingDropDown              matlab.ui.control.DropDown
        C2CSpacingDropDownLabel         matlab.ui.control.Label
        C2CWriteVerifyCheckBox          matlab.ui.control.CheckBox
        C2CsigmaSETEditField            matlab.ui.control.NumericEditField
        C2CsigmaSETEditFieldLabel       matlab.ui.control.Label
        C2CmuSETEditField               matlab.ui.control.NumericEditField
        C2CmuSETEditFieldLabel          matlab.ui.control.Label
        C2CDistributionDropDown         matlab.ui.control.DropDown
        C2CDistributionDropDownLabel    matlab.ui.control.Label
        C2CButtonGroup                  matlab.ui.container.ButtonGroup
        C2CUserinputButton              matlab.ui.control.RadioButton
        C2CExtractfromdataButton        matlab.ui.control.RadioButton
        C2CVariabilityCheckBox          matlab.ui.control.CheckBox
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        NNDefinitionTab                 matlab.ui.container.Tab
        NNSessionManagerPanel           matlab.ui.container.Panel
        SessionManagerControlPanel      matlab.ui.container.Panel
        ShutdownCheckBox                matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        LoadSessionTableButton          matlab.ui.control.Button
        LoadSessionButton               matlab.ui.control.Button
        SaveSessionTableButton          matlab.ui.control.Button
        SaveSessionButton               matlab.ui.control.Button
        MovetoTopButton                 matlab.ui.control.Button
        MovetoBottomButton              matlab.ui.control.Button
        MoveDownButton                  matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        SessionNameControlPanelEditField  matlab.ui.control.EditField
        SessionNameControlPanelEditFieldLabel  matlab.ui.control.Label
        RemoveSessionButton             matlab.ui.control.Button
        NNSessionManagerTable           matlab.ui.control.Table
        NNDefinitionPanel               matlab.ui.container.Panel
        NNDefinitionOptionsTabGroup     matlab.ui.container.TabGroup
        NNDefinitionOptionsTab1         matlab.ui.container.Tab
        NumberofRunsEditField           matlab.ui.control.NumericEditField
        NumberofRunsEditFieldLabel      matlab.ui.control.Label
        ValidationDataEditField         matlab.ui.control.NumericEditField
        ValidationDataEditFieldLabel    matlab.ui.control.Label
        RemoveDatastoreFileButton       matlab.ui.control.Button
        AddDatastoreFileButton          matlab.ui.control.Button
        RemoveNNFileButton              matlab.ui.control.Button
        AddNNFileButton                 matlab.ui.control.Button
        ProgrammingMethodDropDown       matlab.ui.control.DropDown
        ProgrammingMethodDropDownLabel  matlab.ui.control.Label
        RemoveDiscretizationFileButton  matlab.ui.control.Button
        AddDiscretizationFileButton     matlab.ui.control.Button
        RemoveC2CFileButton             matlab.ui.control.Button
        AddC2CFileButton                matlab.ui.control.Button
        RemoveD2DFileButton             matlab.ui.control.Button
        AddD2DFileButton                matlab.ui.control.Button
        SessionNameNNDefinitionEditField  matlab.ui.control.EditField
        SessionNameNNDefinitionEditFieldLabel  matlab.ui.control.Label
        DisturbancefilesUITableLabel    matlab.ui.control.Label
        DisturbancefilesUITable         matlab.ui.control.Table
        NNOptionsTextArea               matlab.ui.control.TextArea
        NNOptionsTextAreaLabel          matlab.ui.control.Label
        NNLayersTextArea                matlab.ui.control.TextArea
        NNLayersTextAreaLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UsePreviousNNCheckBox           matlab.ui.control.CheckBox
        NNDefinitionOptionsTab2         matlab.ui.container.Tab
        SaveWeightGradientDataCheckBox  matlab.ui.control.CheckBox
        WeightRangeRescalingPanel       matlab.ui.container.Panel
        FixedLimitsEditField            matlab.ui.control.EditField
        FixedLimitsEditFieldLabel       matlab.ui.control.Label
        FixedWeightLimitsWarningLabel   matlab.ui.control.Label
        WeightRangeRescalingMethodButtonGroup  matlab.ui.container.ButtonGroup
        FixedRangeButton                matlab.ui.control.RadioButton
        DynamicRangeButton              matlab.ui.control.RadioButton
        NNDefinitionPlotOptions         matlab.ui.container.Panel
        PlotatendofallrunsPanel         matlab.ui.container.Panel
        AllRunSummaryPlotsCheckBox      matlab.ui.control.CheckBox
        PlotEveryRunPanel               matlab.ui.container.Panel
        GradientHeatmapsCheckBox        matlab.ui.control.CheckBox
        WeightGradientHeatmapWarningLabel  matlab.ui.control.Label
        WeightHeatmapsCheckBox          matlab.ui.control.CheckBox
        GradCAMCheckBox                 matlab.ui.control.CheckBox
        WeightGradientHistogramsCheckBox  matlab.ui.control.CheckBox
        TrainingProgressPlotCheckBox    matlab.ui.control.CheckBox
        AddInferenceSessionButton       matlab.ui.control.Button
        AddTrainSessionButton           matlab.ui.control.Button
        LimitedPrecisionTab             matlab.ui.container.Tab
        LimitedPrecisionPanel           matlab.ui.container.Panel
    end

    
    properties (Access = private)
        RRAMTable % Table to be loaded on to the program; Col1 = Pulse; Col2 = V; Col3 = absI
        DataCells % 2 col Matrix of Cells each containing a table refering to the cycle in Row#; Col1 is for SET; Col2 is for RESET; Col order is reversed if RESET is the first step in the RAW Data Table
        PaddedDataCells % Description
        MeanG % 1-by-2 cells; cell(1) contains mean Ref G of LTP and cell(2) mean Ref G of LTD (or vice-versa if data starts with LTD)
        GMatrix % 1-by-2 cells; cell(1) contains a M-by-N Matrix containing all G's of all Cycles of LTP and cell(2) all LTD. M is max number of pulses, N is number of cycles
        NormMeanG % Description
        NormGMatrix % Description
        LowerRefValue % Description
        UpperRefValue % Description
        RefCycle % Description
        NormRefCycle % Description
        TargetG % Description
        NormalizedWeight % Description
        PulseRefArray % Description
        Residuals % Description
        WeightConversionMethod % Description
        DiscretizationStruct % Description
        C2CStruct % Description
        D2DStruct % Description
        appOptions % Description
    end
    
    methods (Access = private)
        
        function C2CStruct = Generate_C2CStruct(app)
            %%C2C
            C2CStruct.dist = app.C2CDistributionDropDown.Value;
            
            % Define Param Names
            switch C2CStruct.dist
                case {'Normal', 'ExtremeValue', 'HalfNormal', 'Lognormal', 'Logistic', 'Loglogistic'}
                    C2CStruct.nParams = 2;
                    C2CStruct.ParamA.Name = 'mu';
                    C2CStruct.ParamB.Name = 'sigma';
                case {'Exponential'}
                    C2CStruct.nParams = 1;
                    C2CStruct.ParamA.Name = 'mu';
                case {'Rayleigh'}
                    C2CStruct.nParams = 1;
                    C2CStruct.ParamA.Name = 'beta';
                case {'Weibull'}
                    C2CStruct.nParams = 2;
                    C2CStruct.ParamA.Name = 'alpha';
                    C2CStruct.ParamB.Name = 'beta';
            end
            
            switch app.C2CButtonGroup.SelectedObject.Text
                case 'User-input'
                    % User-Input case
                    C2CStruct.UserInput_bool = true;
                    switch C2CStruct.nParams
                        case 1
                            %Values
                            C2CStruct.ParamA.Values{1} = app.C2CmuSETEditField.Value;
                            C2CStruct.ParamA.Values{2} = app.C2CmuRESETEditField.Value;
                            %Fit
                            x = [0,1]';
                            y = [C2CStruct.ParamA.Values{1}, C2CStruct.ParamA.Values{1}]';
                            C2CStruct.ParamA.Fit{1} = fit(x, y, 'poly1');
                            y = [C2CStruct.ParamA.Values{2}, C2CStruct.ParamA.Values{2}]';
                            C2CStruct.ParamA.Fit{2} = fit(x,y, 'poly1');
                        case 2
                            %Values
                            C2CStruct.ParamA.Values{1} = app.C2CmuSETEditField.Value;
                            C2CStruct.ParamA.Values{2} = app.C2CmuRESETEditField.Value;
                            C2CStruct.ParamB.Values{1} = app.C2CsigmaSETEditField.Value;
                            C2CStruct.ParamB.Values{2} = app.C2CsigmaRESETEditField.Value;
                            %Fit
                            x = [0,1]';
                            %ParamA
                            y = [C2CStruct.ParamA.Values{1}, C2CStruct.ParamA.Values{1}]';
                            C2CStruct.ParamA.Fit{1} = fit(x,y, 'poly1');
                            y = [C2CStruct.ParamA.Values{2}, C2CStruct.ParamA.Values{2}]';
                            C2CStruct.ParamA.Fit{2} = fit(x,y, 'poly1');
                            %ParamB
                            y = [C2CStruct.ParamB.Values{1}, C2CStruct.ParamB.Values{1}]';
                            C2CStruct.ParamB.Fit{1} = fit(x,y, 'poly1');
                            y = [C2CStruct.ParamB.Values{2}, C2CStruct.ParamB.Values{2}]';
                            C2CStruct.ParamB.Fit{2} = fit(x,y, 'poly1');
                    end               
                    
                case 'Extract from data'
                    % Extract from Data case                    
                    C2CStruct.UserInput_bool = false;                  
                    args.nParams = C2CStruct.nParams;
                    args.dist = C2CStruct.dist;
                    
                    switch C2CStruct.nParams
                        case 1
                            C2CStruct.ParamA.Values = GetParamValues(app.Residuals, args);
                        case 2
                            [C2CStruct.ParamA.Values, C2CStruct.ParamB.Values] = GetParamValues(app.Residuals, args);                  
                    end
                    
                    %%C2C ParamFits along Normalized Weight Range
                    switch C2CStruct.nParams
                        case 1
                            [C2CStruct.ParamA.Fit] = GetParamFits(app.NormalizedWeight, ...
                                app.C2CFitModelEditField.Value, C2CStruct.ParamA.Values);
                        case 2
                            [C2CStruct.ParamA.Fit, C2CStruct.ParamB.Fit] = ...
                                GetParamFits(app.NormalizedWeight, app.C2CFitModelEditField.Value, ...
                                C2CStruct.ParamA.Values, C2CStruct.ParamB.Values);
                    end
                    
            end
            
            C2CStruct.WeightConversion = app.WeightConversionDropDown.Value;
            
        end
        
        function D2DStruct = Generate_D2DStruct(app)
           D2DStruct.dist = app.D2DDistributionDropDown.Value;
           
           % Define Param Names
            switch D2DStruct.dist
                case {'Normal', 'ExtremeValue', 'HalfNormal', 'Lognormal', 'Logistic', 'Loglogistic'}
                    D2DStruct.nParams = 2;
                    D2DStruct.ParamA.Name = 'mu';
                    D2DStruct.ParamB.Name = 'sigma';
                case {'Exponential'}
                    D2DStruct.nParams = 1;
                    D2DStruct.ParamA.Name = 'mu';
                case {'Rayleigh'}
                    D2DStruct.nParams = 1;
                    D2DStruct.ParamA.Name = 'beta';
                case {'Weibull'}
                    D2DStruct.nParams = 2;
                    D2DStruct.ParamA.Name = 'alpha';
                    D2DStruct.ParamB.Name = 'beta';
            end
            
            switch D2DStruct.nParams
                case 1
                    %Values
                    D2DStruct.ParamA.Values = app.D2DmuEditField.Value;
                    %Fit
                    x = [0,1]';
                    y = [D2DStruct.ParamA.Values, D2DStruct.ParamA.Values]';
                    D2DStruct.ParamA.Fit = fit(x, y, 'poly1');
                case 2
                    %Values
                    D2DStruct.ParamA.Values = app.D2DmuEditField.Value;
                    D2DStruct.ParamB.Values = app.D2DsigmaEditField.Value;
                    %Fit
                    x = [0,1]';
                    %ParamA
                    y = [D2DStruct.ParamA.Values, D2DStruct.ParamA.Values]';
                    D2DStruct.ParamA.Fit = fit(x, y, 'poly1');
                    %ParamB
                    y = [D2DStruct.ParamB.Values, D2DStruct.ParamB.Values]';
                    D2DStruct.ParamB.Fit = fit(x, y, 'poly1');
                    
            end
           
            D2DStruct.WeightConversion = app.WeightConversionDropDown.Value;
            
        end
        
        function appOptions = Generate_appOptions(app)
            appOptions = struct('Method', app.MethodDropDown.Value, ...
                'WeightConversion', app.WeightConversionDropDown.Value, ...
                'Normalize', app.NormalizeDropDown.Value, ...
                'RefCycle', app.RefCycleDropDown.Value, ...
                'RefCycleNumber', app.RefCycleEditField.Value, ...
                'UpperRefLimit', app.NormUpperRefLimitDropDown.Value, ...
                'UpperRefValue', app.UpperRefValue, ...
                'LowerRefLimit', app.NormLowerRefLimitDropDown.Value, ...
                'LowerRefValue', app.LowerRefValue, ...
                'C2CExtraction', app.C2CButtonGroup.SelectedObject.Text, ...
                'Write_Verify', app.C2CWriteVerifyCheckBox.Value, ...
                'Spacing', app.C2CSpacingDropDown.Value, ...
                'C2CFitNumberOfPoints', app.C2CFitNumberofPointsEditField.Value, ...
                'C2CFitModel', app.C2CFitModelEditField.Value);           
        end
        
        function DiscretizationStruct = Generate_DiscretizationStruct(app)
            %Input parameters:
            % Import NormRefCycle
            % (1) Ref_cycle NormPulse : XAxis
            % (2) Ref_cycle NormG : YAxis
            %  Model for SET and RESET
            
            %% SET
            %Model
            DiscretizationStruct.Model{1} = fittype(app.DiscretizationModelSETEditField.Value);
            %FitOptions
            fopt = fitoptions(DiscretizationStruct.Model{1});
            temp = get(fopt);
            if isfield(temp, 'TolFun') && isfield(temp, 'TolX')
                fopt = fitoptions(fopt, ...
                    'TolFun', 1e-25, ...
                    'TolX', 1e-25);
            end
            %Fit
            DiscretizationStruct.Fit{1} = fit(app.NormRefCycle{1}.NormPulse, app.NormRefCycle{1}.G, DiscretizationStruct.Model{1}, fopt);
            
            clear temp fopt;
            %DependentName
            DiscretizationStruct.DependentVar{1} = dependnames(DiscretizationStruct.Fit{1});
            %IndependentName
            DiscretizationStruct.IndependentVar{1} = indepnames(DiscretizationStruct.Fit{1});
            %Coeffs
            DiscretizationStruct.Coeff.Names{1} = coeffnames(DiscretizationStruct.Fit{1});
            DiscretizationStruct.Coeff.Values{1} = coeffvalues(DiscretizationStruct.Fit{1});
            %NPulses
            DiscretizationStruct.NPulses{1} = size(app.NormRefCycle{1}.NormPulse, 1);
            
            %% RESET
            %Model
            DiscretizationStruct.Model{2} = fittype(app.DiscretizationModelRESETEditField.Value);
            %FitOptions
            fopt = fitoptions(DiscretizationStruct.Model{2});
            temp = get(fopt);
            if isfield(temp, 'TolFun') && isfield(temp, 'TolX')
                fopt = fitoptions(fopt, ...
                    'TolFun', 1e-25, ...
                    'TolX', 1e-25);
            end
            %Fit
            DiscretizationStruct.Fit{2} = fit(flipud(app.NormRefCycle{2}.NormPulse), app.NormRefCycle{2}.G, DiscretizationStruct.Model{2}, fopt);
            
            clear temp fopt;
            %DependentName
            DiscretizationStruct.DependentVar{2} = dependnames(DiscretizationStruct.Fit{2});
            %IndependentName
            DiscretizationStruct.IndependentVar{2} = indepnames(DiscretizationStruct.Fit{2});
            %Coeffs
            DiscretizationStruct.Coeff.Names{2} = coeffnames(DiscretizationStruct.Fit{2});
            DiscretizationStruct.Coeff.Values{2} = coeffvalues(DiscretizationStruct.Fit{2});
            %NPulses
            DiscretizationStruct.NPulses{2} = size(app.NormRefCycle{2}.NormPulse, 1);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("utils"));
            addpath("Icons");
            addpath(genpath("Datasets"));
            addpath(fullfile(matlabroot,'examples','nnet','main'));
            
            %Initialize UITables
            set(app.DisturbancefilesUITable, 'Data', {''; ''; ''; ''; ''});
        end

        % Button pushed function: RRAMDataFileBrowseButton
        function RRAMDataFileBrowseButtonPushed(app, event)
            [file, path] = uigetfile;
            app.RRAMDataFileEditField.Value = fullfile(path, file);
            clear file path;
        end

        % Button pushed function: RRAMDataFileLoadButton
        function RRAMDataFileLoadButtonPushed(app, event)
            %Load Data Table
            load(app.RRAMDataFileEditField.Value, 'Data');
            app.RRAMTable = Data; % Only works with Tables stored in Data.mat file for now
            clear Data;
            
            %Extract Data parameters from Raw Data Table
            %G
            app.RRAMTable.G = abs(app.RRAMTable.absI ./ app.VReadEditField.Value);
            %NormG
            app.RRAMTable.NormG = rescale(app.RRAMTable.G); % Normalization should be done in the very beginning to account for the data in all cycles
            
            %Convert Raw Data into Cell Matrix
            app.DataCells = RawData_to_Cells(app.RRAMTable);
            
            % Normalize Data Function

            switch app.NormalizeDropDown.Value
                case "All Cycles"
                    [app.DataCells, app.RRAMTable, app.LowerRefValue, app.UpperRefValue] = NormalizeData(app.RRAMTable, ...
                        app.DataCells, ...
                        app.NormalizeDropDown.Value, ...
                        app.NormUpperRefLimitDropDown.Value, ...
                        app.NormLowerRefLimitDropDown.Value);
                case "Individually"
                    [app.DataCells, app.RRAMTable] = NormalizeData(app.RRAMTable, ...
                        app.DataCells, ...
                        app.NormalizeDropDown.Value, ...
                        app.NormUpperRefLimitDropDown.Value, ...
                        app.NormLowerRefLimitDropDown.Value);
            end
            
            
            if size(app.DataCells,1) > 1
            % Pad Missing values in ISPP Programming scheme (i.e. Linear Response)
            app.PaddedDataCells = PadData(app.DataCells);
            
            % Change the limit of app.RefCycleEditField to account for the
            % number of cycles
            app.RefCycleEditField.Limits = [1, size(app.PaddedDataCells,1)];
            
            % Get Mean Ref G
            % !! Revise after completing Normalize Data Function !!
            [app.MeanG, app.GMatrix, app.NormMeanG, app.NormGMatrix, app.RefCycle, app.NormRefCycle] = ...
                GetMeanG(app.PaddedDataCells, app.RefCycleDropDown.Value, app.RefCycleEditField.Value);
            
            % Get TargetG
            [app.TargetG, app.PulseRefArray] = GetTargetG(app.RefCycle, ...
                app.C2CSpacingDropDown.Value, ...
                app.C2CFitNumberofPointsEditField.Value, ...
                app.NormalizeDropDown.Value, ...
                app.LowerRefValue, app.UpperRefValue);
            
            for i = 1 : size(app.TargetG, 2)
                app.NormalizedWeight{1,i} = rescale(app.TargetG{1,i});
            end
            
            %% Calculate Residuals
            if any(strcmp(app.C2CDistributionDropDown.Value, {'Lognormal', 'Loglogistic', 'Rayleigh', 'Weibull'}))
                nonzero_bool = true;
            else
                nonzero_bool = false;
            end
            
            app.WeightConversionMethod = app.WeightConversionDropDown.Value;
            
            app.Residuals = CalcResiduals(app.GMatrix, app.TargetG, app.PulseRefArray, app.WeightConversionMethod, app.C2CWriteVerifyCheckBox.Value, nonzero_bool);
            %%
            
            %% Discretization Model
            app.DiscretizationStruct = Generate_DiscretizationStruct(app);
            %%
            
            %% C2C
            app.C2CStruct = Generate_C2CStruct(app);
            %%
            
            %% D2D
            app.D2DStruct = Generate_D2DStruct(app);
            %%
            
            %% appOptions
            app.appOptions = Generate_appOptions(app);
            %%
            %% Plot All
            PlotAllButtonPushed(app, event);
            %%
            elseif size(app.DataCells,1) == 1 %Hotfix for 1 cycle data               
                app.NormRefCycle{1} = table(app.DataCells{1,1}.NormPulse, app.DataCells{1,1}.NormG, 'VariableNames', {'NormPulse', 'G'});
                app.NormRefCycle{2} = table(app.DataCells{1,2}.NormPulse, app.DataCells{1,2}.NormG, 'VariableNames', {'NormPulse', 'G'});
                
                app.DiscretizationStruct = Generate_DiscretizationStruct(app);           
                
                RawDataPlotButtonPushed(app, event);
                ModelPlotButtonPushed(app, event);
            end
        end

        % Button pushed function: RawDataPlotButton
        function RawDataPlotButtonPushed(app, event)
            % Plot Raw Data (Plot tab 1)
            switch app.NormalizeDropDown.Value
                case "All Cycles"
                    temp_ax_children = Plot_RawData(app.RRAMTable, app.LowerRefValue, app.UpperRefValue);
                case "Individually"
                    temp_ax_children = Plot_RawData(app.RRAMTable);
            end
            
            cla(app.RawDataUIAxes);
            copyobj(temp_ax_children, app.RawDataUIAxes);
            clear temp_ax_children;
            close gcf;
        end

        % Button pushed function: ModelPlotButton
        function ModelPlotButtonPushed(app, event)
            cla(app.ModelUIAxes);
            
            fig1 = figure;
            plot(app.DiscretizationStruct.Fit{1}, 'r-', app.NormRefCycle{1}.NormPulse, app.NormRefCycle{1}.G, 'ro');
            hold on;
            plot(app.DiscretizationStruct.Fit{2}, 'b-', flipud(app.NormRefCycle{2}.NormPulse), app.NormRefCycle{2}.G, 'bs');
            
            %Set graph properties
            set(fig1.Children(2).Children(1), 'LineWidth', 1.5); %RESET Line
            set(fig1.Children(2).Children(2), 'MarkerSize', 4); %RESET Marker
            set(fig1.Children(2).Children(3), 'LineWidth', 1.5); %SET Line
            set(fig1.Children(2).Children(4), 'MarkerSize', 4); %SET Marker
            
            copyobj(fig1.Children(2).Children, app.ModelUIAxes);
            close gcf;
            
            legend(app.ModelUIAxes, "SET Data", "SET Fit", "RESET Data", "RESET Fit", 'Location', 'northwest');
        end

        % Button pushed function: D2DStatFitPlotButton
        function D2DStatFitPlotButtonPushed(app, event)
            %%Clear Axes
            cla(app.D2DStatFitUIAxes, 'reset');
            %%

            switch app.D2DStruct.nParams
                case 1
                    R = random(app.D2DStruct.dist, app.D2DStruct.ParamA.Values, [100,1]);
                case 2                  
                    %100 defines the number of dummy points to be shown
                    %in this plot. These points have no other meaning
                    %than to show what the distribution would look like
                    %with some dummy data
                    R = random(app.D2DStruct.dist, app.D2DStruct.ParamA.Values, app.D2DStruct.ParamB.Values, [100,1]);            
            end
            
            probplot(app.D2DStatFitUIAxes, app.D2DStruct.dist, R);
            xlabel(app.D2DStatFitUIAxes, app.WeightConversionMethod);
        end

        % Button pushed function: D2DNormWeightFitPlotButton
        function D2DNormWeightFitPlotButtonPushed(app, event)
            cla(app.D2DNormWeightFitUIAxesParamA, 'reset');
            cla(app.D2DNormWeightFitUIAxesParamB, 'reset');

            %%ParamA
            fig1 = figure;
            plot(app.D2DStruct.ParamA.Fit);
            copyobj(fig1.Children(2).Children, app.D2DNormWeightFitUIAxesParamA);
            xlabel(app.D2DNormWeightFitUIAxesParamA, 'Normalized Weight');
            ylabel(app.D2DNormWeightFitUIAxesParamA, strcat('\', app.D2DStruct.ParamA.Name));
            close gcf;
            %%
            
            %%ParamB
            if app.D2DStruct.nParams > 1
                fig1 = figure;
                plot(app.D2DStruct.ParamB.Fit);
                copyobj(fig1.Children(2).Children, app.D2DNormWeightFitUIAxesParamB);
                xlabel(app.D2DNormWeightFitUIAxesParamB, 'Normalized Weight');
                ylabel(app.D2DNormWeightFitUIAxesParamB, strcat('\', app.D2DStruct.ParamB.Name));
                close gcf;
            end
            
        end

        % Button pushed function: C2CStatFitPlotButton
        function C2CStatFitPlotButtonPushed(app, event)
            %% Clear Axes
            cla(app.C2CStatFitUIAxesSET, 'reset');
            cla(app.C2CStatFitUIAxesRESET, 'reset');
       
            %User-Input_bool
            if strcmp(app.C2CButtonGroup.SelectedObject.Text, 'User-input') == 1
                args.User_Input_bool = true;
            else
                args.User_Input_bool = false;
            end
            
            %Distribution Params
            if args.User_Input_bool == true
                %User-input args             
                switch app.C2CStruct.nParams
                    case 2
                        R_SET = random(app.C2CStruct.dist, app.C2CStruct.ParamA.Values{1}, app.C2CStruct.ParamB.Values{1}, [100, 1]);
                        R_RESET = random(app.C2CStruct.dist, app.C2CStruct.ParamA.Values{2}, app.C2CStruct.ParamB.Values{2}, [100, 1]);
                    case 1
                        %100 defines the number of dummy points to be shown
                        %in this plot. These points have no other meaning
                        %than to show what the distribution would look like
                        %with some dummy data
                        R_SET = random(app.C2CStruct.dist, app.C2CStruct.ParamA.Values{1}, [100, 1]);
                        R_RESET = random(app.C2CStruct.dist, app.C2CStruct.ParamA.Values{2}, [100, 1]);
                end
                
                probplot(app.C2CStatFitUIAxesSET, app.C2CStruct.dist, R_SET);
                probplot(app.C2CStatFitUIAxesRESET, app.C2CStruct.dist, R_RESET);
                
            else
                %Extract from Data args
                if strcmp(app.C2CStatFitPlotDropDown.Value, 'All') == 1
                    %args.PlotAll_bool = true;
                    
                    probplot(app.C2CStatFitUIAxesSET, app.C2CStruct.dist, app.Residuals{1,1}');
                    probplot(app.C2CStatFitUIAxesRESET, app.C2CStruct.dist, app.Residuals{1,2}');
                    
                else 
                    %args.PlotAll_bool = false;
                    args.NormWeightValue = app.C2CNormalizedWeightPlotValueEditField.Value;
                    
                    %% Get idx
                    temp_Row_idx{1} = rescale( 1:1:size(app.Residuals{1,1}, 1) );
                    temp_Row_idx{2} = rescale( 1:1:size(app.Residuals{1,2}, 1) );
                    
                    [~, idx(1)] = min( abs( temp_Row_idx{1} - args.NormWeightValue ) );
                    [~, idx(2)] = min( abs( temp_Row_idx{2} - args.NormWeightValue ) );
                    
                    clear temp_Row_idx;
                    %%
                    
                    %% probplot
                    probplot(app.C2CStatFitUIAxesSET, app.C2CStruct.dist, app.Residuals{1,1}(idx(1), :));
                    probplot(app.C2CStatFitUIAxesRESET, app.C2CStruct.dist, app.Residuals{1,2}(idx(2), :));
                    %%
                end
            end
            
            title(app.C2CStatFitUIAxesSET, strcat("SET-Only ", app.C2CStruct.dist, " Probability plot"));
            xlabel(app.C2CStatFitUIAxesSET, app.WeightConversionMethod);
            
            title(app.C2CStatFitUIAxesRESET, strcat("RESET-Only ", app.C2CStruct.dist, " Probability plot"));
            xlabel(app.C2CStatFitUIAxesRESET, app.WeightConversionMethod);
        end

        % Button pushed function: C2CNormWeightPlotButton
        function C2CNormWeightPlotButtonPushed(app, event)
            % !! Continue here !!
            % !! Next:
            
            % (3) Add the Fitting of these C2C Params functionality for the
            % two types:
            %       (1) Single Distribution variability
            %       (2) Multi Distribution variability
            
            %% clear axes
            cla(app.C2CNormWeightUIAxesSETParamA);
            cla(app.C2CNormWeightUIAxesRESETParamA);
            cla(app.C2CNormWeightUIAxesSETParamB);
            cla(app.C2CNormWeightUIAxesRESETParamB);
            %%
            
            if app.C2CStruct.UserInput_bool == true
                %%ParamA
                fig1 = figure;
                plot(app.C2CStruct.ParamA.Fit{1}, [0,1]', [app.C2CStruct.ParamA.Values{1}, app.C2CStruct.ParamA.Values{1}]');
                %SET
                copyobj(fig1.Children(2).Children, app.C2CNormWeightUIAxesSETParamA);
                xlabel(app.C2CNormWeightUIAxesSETParamA, 'Normalized Weight');
                ylabel(app.C2CNormWeightUIAxesSETParamA, strcat('\', app.C2CStruct.ParamA.Name));
                title(app.C2CNormWeightUIAxesSETParamA, 'SET');
                
                close gcf;
                
                fig1 = figure;
                plot(app.C2CStruct.ParamA.Fit{2}, [0,1]', [app.C2CStruct.ParamA.Values{2}, app.C2CStruct.ParamA.Values{2}]');
                %RESET
                copyobj(fig1.Children(2).Children, app.C2CNormWeightUIAxesRESETParamA);
                xlabel(app.C2CNormWeightUIAxesRESETParamA, 'Normalized Weight');
                ylabel(app.C2CNormWeightUIAxesRESETParamA, strcat('\', app.C2CStruct.ParamA.Name));
                title(app.C2CNormWeightUIAxesRESETParamA, 'RESET');
                
                close gcf;
                %%
                
                %%ParamB
                fig1 = figure;
                plot(app.C2CStruct.ParamB.Fit{1}, [0,1]', [app.C2CStruct.ParamB.Values{1}, app.C2CStruct.ParamB.Values{1}]');
                %SET
                copyobj(fig1.Children(2).Children, app.C2CNormWeightUIAxesSETParamB);
                xlabel(app.C2CNormWeightUIAxesSETParamB, 'Normalized Weight');
                ylabel(app.C2CNormWeightUIAxesSETParamB, strcat('\', app.C2CStruct.ParamB.Name));
                
                close gcf;
                
                fig1 = figure;
                plot(app.C2CStruct.ParamB.Fit{2}, [0,1]', [app.C2CStruct.ParamB.Values{2}, app.C2CStruct.ParamB.Values{2}]');
                %RESET
                copyobj(fig1.Children(2).Children, app.C2CNormWeightUIAxesRESETParamB);
                xlabel(app.C2CNormWeightUIAxesRESETParamB, 'Normalized Weight');
                ylabel(app.C2CNormWeightUIAxesRESETParamB, strcat('\', app.C2CStruct.ParamB.Name));
                
                close gcf;
                %%
            else
                %%ParamA
                %SET                
                fig1 = figure;
                plot(app.C2CStruct.ParamA.Fit{1}, app.NormalizedWeight{1}, app.C2CStruct.ParamA.Values{1});
                copyobj(fig1.Children(2).Children, app.C2CNormWeightUIAxesSETParamA);
                xlabel(app.C2CNormWeightUIAxesSETParamA, 'Normalized Weight');
                ylabel(app.C2CNormWeightUIAxesSETParamA, strcat('\', app.C2CStruct.ParamA.Name));
                title(app.C2CNormWeightUIAxesSETParamA, 'SET');
                close gcf;
                
                %RESET
                fig1 = figure;
                plot(app.C2CStruct.ParamA.Fit{2}, app.NormalizedWeight{2}, app.C2CStruct.ParamA.Values{2});
                copyobj(fig1.Children(2).Children, app.C2CNormWeightUIAxesRESETParamA);
                xlabel(app.C2CNormWeightUIAxesRESETParamA, 'Normalized Weight');
                ylabel(app.C2CNormWeightUIAxesRESETParamA, strcat('\', app.C2CStruct.ParamA.Name));
                title(app.C2CNormWeightUIAxesRESETParamA, 'RESET');
                close gcf;
                %%
                
                if app.C2CStruct.nParams > 1
                    %%ParamB
                    %SET                   
                    fig1 = figure;
                    plot(app.C2CStruct.ParamB.Fit{1}, app.NormalizedWeight{1}, app.C2CStruct.ParamB.Values{1});
                    copyobj(fig1.Children(2).Children, app.C2CNormWeightUIAxesSETParamB);
                    xlabel(app.C2CNormWeightUIAxesSETParamB, 'Normalized Weight');
                    ylabel(app.C2CNormWeightUIAxesSETParamB, strcat('\', app.C2CStruct.ParamB.Name));
                    close gcf;
                    
                    %RESET                 
                    fig1 = figure;
                    plot(app.C2CStruct.ParamB.Fit{2}, app.NormalizedWeight{2}, app.C2CStruct.ParamB.Values{2});
                    copyobj(fig1.Children(2).Children, app.C2CNormWeightUIAxesRESETParamB);
                    xlabel(app.C2CNormWeightUIAxesRESETParamB, 'Normalized Weight');
                    ylabel(app.C2CNormWeightUIAxesRESETParamB, strcat('\', app.C2CStruct.ParamB.Name));
                    close gcf;
                    %%
                end
            end
            %C2CNormWeightUIAxesSETParamAButtonDown(app);
        end

        % Button pushed function: PlotAllButton
        function PlotAllButtonPushed(app, event)
            RawDataPlotButtonPushed(app,event);
            ModelPlotButtonPushed(app,event);
            D2DStatFitPlotButtonPushed(app, event);
            D2DNormWeightFitPlotButtonPushed(app, event);
            C2CStatFitPlotButtonPushed(app, event);
            C2CNormWeightPlotButtonPushed(app, event);
            % Add the remaining plots once completed
        end

        % Value changed function: RefCycleDropDown
        function RefCycleDropDownValueChanged(app, event)
            value = app.RefCycleDropDown.Value;
            if strcmp(value, "From Data") == 1
                app.RefCycleEditField.Visible = true;
                app.RefCycleEditFieldLabel.Visible = true;
            else
                app.RefCycleEditField.Visible = false;
                app.RefCycleEditFieldLabel.Visible = false;
            end
        end

        % Value changed function: D2DDistributionDropDown
        function D2DDistributionDropDownValueChanged(app, event)
            value = app.D2DDistributionDropDown.Value;
            switch value
                case {'Normal', 'ExtremeValue', 'HalfNormal', 'Lognormal', 'Logistic', 'Loglogistic'}
                    %Label Names
                    app.D2DmuEditFieldLabel.Text = 'mu';
                    app.D2DsigmaEditFieldLabel.Text = 'sigma';
                    %Label Visible
                    app.D2DmuEditFieldLabel.Visible = true;
                    app.D2DsigmaEditFieldLabel.Visible = true;
                    %EditField Visible
                    app.D2DmuEditField.Visible = true;
                    app.D2DsigmaEditField.Visible = true;
                case 'Exponential'
                    %Label Names
                    app.D2DmuEditFieldLabel.Text = 'mu';
                    %Label Visible
                    app.D2DmuEditFieldLabel.Visible = true;
                    app.D2DsigmaEditFieldLabel.Visible = false;
                    %EditField Visible
                    app.D2DmuEditField.Visible = true;
                    app.D2DsigmaEditField.Visible = false;
                case 'Rayleigh'
                    %Label Names
                    app.D2DmuEditFieldLabel.Text = 'b';
                    %Label Visible
                    app.D2DmuEditFieldLabel.Visible = true;
                    app.D2DsigmaEditFieldLabel.Visible = false;
                    %EditField Visible
                    app.D2DmuEditField.Visible = true;
                    app.D2DsigmaEditField.Visible = false;
                case 'Weibull'
                    %Label Names
                    app.D2DmuEditFieldLabel.Text = 'a';
                    app.D2DsigmaEditFieldLabel.Text = 'b';
                    %Label Visible
                    app.D2DmuEditFieldLabel.Visible = true;
                    app.D2DsigmaEditFieldLabel.Visible = true;
                    %EditField Visible
                    app.D2DmuEditField.Visible = true;
                    app.D2DsigmaEditField.Visible = true;
            end
        end

        % Selection changed function: C2CButtonGroup
        function C2CButtonGroupSelectionChanged(app, event)
            selectedButton = app.C2CButtonGroup.SelectedObject;
            dropdownValue = app.C2CDistributionDropDown.Value;
            switch selectedButton.Text
                case 'User-input'
                    %Fit Model
                    app.C2CFitNumberofPointsEditField.Visible = false;
                    app.C2CFitNumberofPointsEditFieldLabel.Visible = false;
                    app.C2CFitModelEditField.Visible = false;
                    app.C2CFitModelEditFieldLabel.Visible = false;
                    app.C2CFitModelHelpImage.Visible = false;
                    %
                    app.C2CWriteVerifyCheckBox.Visible = false;
                    app.C2CSpacingDropDown.Visible = false;
                    app.C2CSpacingDropDownLabel.Visible = false;                  
                    switch dropdownValue
                        case {'Normal', 'ExtremeValue', 'HalfNormal', 'Lognormal', 'Logistic', 'Loglogistic', 'Weibull'}
                            %Label Visible
                            app.C2CmuSETEditFieldLabel.Visible = true;
                            app.C2CsigmaSETEditFieldLabel.Visible = true;
                            app.C2CmuRESETEditFieldLabel.Visible = true;
                            app.C2CsigmaRESETEditFieldLabel.Visible = true;
                            %EditField Visible
                            app.C2CmuSETEditField.Visible = true;
                            app.C2CsigmaSETEditField.Visible = true;
                            app.C2CmuRESETEditField.Visible = true;
                            app.C2CsigmaRESETEditField.Visible = true;
                        case {'Exponential', 'Rayleigh'}
                            %Label Visible
                            app.C2CmuSETEditFieldLabel.Visible = true;
                            app.C2CsigmaSETEditFieldLabel.Visible = false;
                            app.C2CmuRESETEditFieldLabel.Visible = true;
                            app.C2CsigmaRESETEditFieldLabel.Visible = false;
                            %EditField Visible
                            app.C2CmuSETEditField.Visible = true;
                            app.C2CsigmaSETEditField.Visible = false;
                            app.C2CmuRESETEditField.Visible = true;
                            app.C2CsigmaRESETEditField.Visible = false;
                    end
                    
                    if strcmp(app.C2CStatFitPlotDropDown.Value, 'User-Input') == 0
                        app.C2CStatFitPlotDropDown.Value = 'User-Input';
                        C2CStatFitPlotDropDownValueChanged(app);
                    end
                case 'Extract from data'
                    %Fit Model
                    app.C2CFitNumberofPointsEditField.Visible = true;
                    app.C2CFitNumberofPointsEditFieldLabel.Visible = true;
                    app.C2CFitModelEditField.Visible = true;
                    app.C2CFitModelEditFieldLabel.Visible = true;
                    app.C2CFitModelHelpImage.Visible = true;
                    %
                    %Label Visible
                    app.C2CmuSETEditFieldLabel.Visible = false;
                    app.C2CsigmaSETEditFieldLabel.Visible = false;
                    app.C2CmuRESETEditFieldLabel.Visible = false;
                    app.C2CsigmaRESETEditFieldLabel.Visible = false;
                    
                    app.C2CSpacingDropDownLabel.Visible = true;
                    %EditField Visible
                    app.C2CmuSETEditField.Visible = false;
                    app.C2CsigmaSETEditField.Visible = false;
                    app.C2CmuRESETEditField.Visible = false;
                    app.C2CsigmaRESETEditField.Visible = false;
                    %DropDown Visible
                    app.C2CSpacingDropDown.Visible = true;
                    %CheckBox Visible
                    app.C2CWriteVerifyCheckBox.Visible = true;
                    
                    if ( strcmp(app.C2CStatFitPlotDropDown.Value, 'All') == 0 && ...
                            strcmp(app.C2CStatFitPlotDropDown.Value, 'Single Value') == 0 )
                        app.C2CStatFitPlotDropDown.Value = 'Single Value';
                        C2CStatFitPlotDropDownValueChanged(app);
                    end
            end
        end

        % Value changed function: C2CDistributionDropDown
        function C2CDistributionDropDownValueChanged(app, event)
            value = app.C2CDistributionDropDown.Value;
            selectedButton = app.C2CButtonGroup.SelectedObject;
            switch value
                case {'Normal', 'ExtremeValue', 'HalfNormal', 'Lognormal', 'Logistic', 'Loglogistic'}
                    %Label Names
                    app.C2CmuSETEditFieldLabel.Text = 'mu SET';
                    app.C2CsigmaSETEditFieldLabel.Text = 'sigma SET';
                    app.C2CmuRESETEditFieldLabel.Text = 'mu RESET';
                    app.C2CsigmaRESETEditFieldLabel.Text = 'sigma RESET';
                case 'Exponential'
                    %Label Names
                    app.C2CmuSETEditFieldLabel.Text = 'mu SET';
                    app.C2CmuRESETEditFieldLabel.Text = 'mu RESET';
                case 'Rayleigh'
                    %Label Names
                    app.C2CmuSETEditFieldLabel.Text = 'b SET';
                    app.C2CmuRESETEditFieldLabel.Text = 'b RESET';
                case 'Weibull'
                    %Label Names
                    app.C2CmuSETEditFieldLabel.Text = 'a SET';
                    app.C2CsigmaSETEditFieldLabel.Text = 'b SET';
                    app.C2CmuRESETEditFieldLabel.Text = 'a RESET';
                    app.C2CsigmaRESETEditFieldLabel.Text = 'b RESET';
            end
            switch selectedButton.Text
                case 'Extract from data'
                    %Label Visible
                    app.C2CmuSETEditFieldLabel.Visible = false;
                    app.C2CsigmaSETEditFieldLabel.Visible = false;
                    app.C2CmuRESETEditFieldLabel.Visible = false;
                    app.C2CsigmaRESETEditFieldLabel.Visible = false;
                    
                    %EditField Visible
                    app.C2CmuSETEditField.Visible = false;
                    app.C2CsigmaSETEditField.Visible = false;
                    app.C2CmuRESETEditField.Visible = false;
                    app.C2CsigmaRESETEditField.Visible = false;
                    
                case 'User-input'
                    switch value
                        case {'Normal', 'ExtremeValue', 'HalfNormal', 'Lognormal', 'Logistic', 'Loglogistic'}
                            %Label Visible
                            app.C2CmuSETEditFieldLabel.Visible = true;
                            app.C2CsigmaSETEditFieldLabel.Visible = true;
                            app.C2CmuRESETEditFieldLabel.Visible = true;
                            app.C2CsigmaRESETEditFieldLabel.Visible = true;
                            
                            %EditField Visible
                            app.C2CmuSETEditField.Visible = true;
                            app.C2CsigmaSETEditField.Visible = true;
                            app.C2CmuRESETEditField.Visible = true;
                            app.C2CsigmaRESETEditField.Visible = true;
                        case 'Exponential'
                            %Label Visible
                            app.C2CmuSETEditFieldLabel.Visible = true;
                            app.C2CsigmaSETEditFieldLabel.Visible = false;
                            app.C2CmuRESETEditFieldLabel.Visible = true;
                            app.C2CsigmaRESETEditFieldLabel.Visible = false;
                            
                            %EditField Visible
                            app.C2CmuSETEditField.Visible = true;
                            app.C2CsigmaSETEditField.Visible = false;
                            app.C2CmuRESETEditField.Visible = true;
                            app.C2CsigmaRESETEditField.Visible = false;
                        case 'Rayleigh'
                            %Label Visible
                            app.C2CmuSETEditFieldLabel.Visible = true;
                            app.C2CsigmaSETEditFieldLabel.Visible = false;
                            app.C2CmuRESETEditFieldLabel.Visible = true;
                            app.C2CsigmaRESETEditFieldLabel.Visible = false;
                            
                            %EditField Visible
                            app.C2CmuSETEditField.Visible = true;
                            app.C2CsigmaSETEditField.Visible = false;
                            app.C2CmuRESETEditField.Visible = true;
                            app.C2CsigmaRESETEditField.Visible = false;
                        case 'Weibull'
                            %Label Visible
                            app.C2CmuSETEditFieldLabel.Visible = true;
                            app.C2CsigmaSETEditFieldLabel.Visible = true;
                            app.C2CmuRESETEditFieldLabel.Visible = true;
                            app.C2CsigmaRESETEditFieldLabel.Visible = true;
                            
                            %EditField Visible
                            app.C2CmuSETEditField.Visible = true;
                            app.C2CsigmaSETEditField.Visible = true;
                            app.C2CmuRESETEditField.Visible = true;
                            app.C2CsigmaRESETEditField.Visible = true;
                    end
            end
        end

        % Value changed function: C2CStatFitPlotDropDown
        function C2CStatFitPlotDropDownValueChanged(app, event)
            value = app.C2CStatFitPlotDropDown.Value;
            switch value
                case 'All'
                    app.C2CNormalizedWeightPlotValueEditField.Visible = false;
                    app.C2CNormalizedWeightPlotValueEditFieldLabel.Visible = false;
                    app.C2CButtonGroup.SelectedObject = app.C2CExtractfromdataButton;
                    C2CButtonGroupSelectionChanged(app);
                case 'Single Value'
                    app.C2CNormalizedWeightPlotValueEditField.Visible = true;
                    app.C2CNormalizedWeightPlotValueEditFieldLabel.Visible = true;
                    app.C2CButtonGroup.SelectedObject = app.C2CExtractfromdataButton;
                    C2CButtonGroupSelectionChanged(app);
                case 'User-Input'
                    app.C2CNormalizedWeightPlotValueEditField.Visible = false;
                    app.C2CNormalizedWeightPlotValueEditFieldLabel.Visible = false;
                    app.C2CButtonGroup.SelectedObject = app.C2CUserinputButton;
                    C2CButtonGroupSelectionChanged(app);
            end
        end

        % Value changed function: NormalizeDropDown
        function NormalizeDropDownValueChanged(app, event)
            value = app.NormalizeDropDown.Value;
            switch value
                case 'Individually'
                    app.NormUpperRefLimitDropDown.Visible = false;
                    app.NormUpperRefLimitDropDownLabel.Visible = false;
                    app.NormLowerRefLimitDropDown.Visible = false;
                    app.NormLowerRefLimitDropDownLabel.Visible = false;
                case 'All Cycles'
                    app.NormUpperRefLimitDropDown.Visible = true;
                    app.NormUpperRefLimitDropDownLabel.Visible = true;
                    app.NormLowerRefLimitDropDown.Visible = true;
                    app.NormLowerRefLimitDropDownLabel.Visible = true;
            end
        end

        % Button down function: ModelUIAxes
        function ModelUIAxesButtonDown(app, event)
            x{1} = app.NormRefCycle{1}.NormPulse;
            x{2} = flipud(app.NormRefCycle{2}.NormPulse);
            y{1} = app.NormRefCycle{1}.G;
            y{2} = app.NormRefCycle{2}.G;
            
            app.DiscretizationStruct.Fit = Refit_Model(app.DiscretizationStruct.Fit, x, y);
            
            ModelPlotButtonPushed(app);
        end

        % Button down function: C2CNormWeightUIAxesSETParamA
        function C2CNormWeightUIAxesSETParamAButtonDown(app, event)
            if app.C2CStruct.UserInput_bool == false
                ft{1} = app.C2CStruct.ParamA.Fit{1};
                x{1} = app.NormalizedWeight{1};
                y{1} = app.C2CStruct.ParamA.Values{1};
                
                temp_ft = Refit_Model(ft, x, y);
                app.C2CStruct.ParamA.Fit{1} = temp_ft{1};
                
                C2CNormWeightPlotButtonPushed(app);
            end
        end

        % Button down function: C2CNormWeightUIAxesRESETParamA
        function C2CNormWeightUIAxesRESETParamAButtonDown(app, event)
            if app.C2CStruct.UserInput_bool == false
                ft{1} = app.C2CStruct.ParamA.Fit{2};
                x{1} = app.NormalizedWeight{2};
                y{1} = app.C2CStruct.ParamA.Values{2};
                
                temp_ft = Refit_Model(ft, x, y);
                app.C2CStruct.ParamA.Fit{2} = temp_ft{1};
                
                C2CNormWeightPlotButtonPushed(app);
            end
        end

        % Button down function: C2CNormWeightUIAxesSETParamB
        function C2CNormWeightUIAxesSETParamBButtonDown(app, event)
            if app.C2CStruct.UserInput_bool == false
                ft{1} = app.C2CStruct.ParamB.Fit{1};
                x{1} = app.NormalizedWeight{1};
                y{1} = app.C2CStruct.ParamB.Values{1};
                
                temp_ft = Refit_Model(ft, x, y);
                app.C2CStruct.ParamB.Fit{1} = temp_ft{1};
                
                C2CNormWeightPlotButtonPushed(app);
            end
        end

        % Button down function: C2CNormWeightUIAxesRESETParamB
        function C2CNormWeightUIAxesRESETParamBButtonDown(app, event)
            if app.C2CStruct.UserInput_bool == false
                ft{1} = app.C2CStruct.ParamB.Fit{2};
                x{1} = app.NormalizedWeight{2};
                y{1} = app.C2CStruct.ParamB.Values{2};
                
                temp_ft = Refit_Model(ft, x, y);
                app.C2CStruct.ParamB.Fit{2} = temp_ft{1};
                
                C2CNormWeightPlotButtonPushed(app);
            end
        end

        % Image clicked function: DiscretizationFitHelp
        function DiscretizationFitHelpImageClicked(app, event)
            web('https://www.mathworks.com/help/curvefit/list-of-library-models-for-curve-and-surface-fitting.html#btbcvnl');
        end

        % Image clicked function: C2CFitModelHelpImage
        function C2CFitModelHelpImageClicked(app, event)
            web('https://www.mathworks.com/help/curvefit/list-of-library-models-for-curve-and-surface-fitting.html#btbcvnl');
        end

        % Button pushed function: DiscretizationExportStructButton
        function DiscretizationExportStructButtonPushed(app, event)
            if isstruct(app.DiscretizationStruct)
                %% C2C Struct
                DiscretizationStruct = app.DiscretizationStruct;
                %%
                %% Save
                [file, path] = uiputfile('*.mat', 'Save Discretization Struct', 'DiscretizationStruct');
                
                filename = extractBefore(file, '.mat');
                
                if file == 0
                    return;
                end
                
                %% appOptions
                if app.DiscretizationExportappOptionsCheckBox.Value == true
                    appOptions = app.appOptions;
                    save(fullfile(path, file), 'DiscretizationStruct', 'appOptions');
                else
                    save(fullfile(path, file), 'DiscretizationStruct');
                end
                
                %% Export image
                exportgraphics(app.ModelUIAxes, fullfile(path, strcat(filename, '.jpg')), 'Resolution',300);
                %%
                %% Save .fig
                fig1 = figure;
                copyobj(app.ModelUIAxes, fig1);
                savefig(fig1, fullfile(path, strcat(filename, '.fig')));
                close(fig1);
                clear fig1;
                %%
            else
                errordlg('Discretization Struct not defined! Please reload the data');
            end
        end

        % Button pushed function: D2DExportStructButton
        function D2DExportStructButtonPushed(app, event)
            if isstruct(app.C2CStruct)
                %% C2C Struct
                D2DStruct = app.D2DStruct;
                %%
                %% Save
                [file, path] = uiputfile('*.mat', 'Save D2D Struct', 'D2DStruct');
                
                filename = extractBefore(file, '.mat');
                
                if file == 0
                    return;
                end
                
                if app.D2DExportappOptionsCheckBox.Value == true
                    appOptions = app.appOptions;
                    save(fullfile(path, file), 'D2DStruct', 'appOptions');
                else
                    save(fullfile(path, file), 'D2DStruct');
                end
                %% Export Image
                exportgraphics(app.D2DNormWeightFitUIAxesParamA, fullfile(path, strcat(filename, 'ParamA.jpg')), 'Resolution', 300);
                exportgraphics(app.D2DNormWeightFitUIAxesParamB, fullfile(path, strcat(filename, 'ParamB.jpg')), 'Resolution', 300);
                %%
                %% Save .fig
                fig1 = figure;
                copyobj(app.D2DNormWeightFitUIAxesParamA, fig1);
                savefig(fig1, fullfile(path, strcat(filename, 'ParamA.fig')));
                
                fig2 = figure;
                copyobj(app.D2DNormWeightFitUIAxesParamB, fig2);
                savefig(fig2, fullfile(path, strcat(filename, 'ParamB.fig')));
                
                close([fig1, fig2]);
                %clear fig1 fig2;
                %%
            else
                errordlg('D2D Struct not defined! Please reload the data');
            end
        end

        % Button pushed function: C2CExportStructButton
        function C2CExportStructButtonPushed(app, event)
            if isstruct(app.C2CStruct)
                %% C2C Struct
                C2CStruct = app.C2CStruct;
                %%
                %% Save
                [file, path] = uiputfile('*.mat', 'Save C2C Struct', 'C2CStruct');
                
                if file == 0
                    return;
                end
                
                filename = extractBefore(file, '.mat');
                
                if app.C2CExportappOptionsCheckBox.Value == true
                    appOptions = app.appOptions;
                    save(fullfile(path, file), 'C2CStruct', 'appOptions');
                else
                    save(fullfile(path, file), 'C2CStruct');
                end
                %% Export Image
                exportgraphics(app.C2CNormWeightUIAxesSETParamA, fullfile(path, strcat(filename, 'SETParamA.jpg')), 'Resolution', 300);
                exportgraphics(app.C2CNormWeightUIAxesSETParamB, fullfile(path, strcat(filename, 'SETParamB.jpg')), 'Resolution', 300);
                exportgraphics(app.C2CNormWeightUIAxesRESETParamA, fullfile(path, strcat(filename, 'RESETParamA.jpg')), 'Resolution', 300);
                exportgraphics(app.C2CNormWeightUIAxesRESETParamB, fullfile(path, strcat(filename, 'RESETParamB.jpg')), 'Resolution', 300);
                exportgraphics(app.C2CStatFitUIAxesSET, fullfile(path, strcat(filename, 'SETDist.jpg')), 'Resolution', 300);
                exportgraphics(app.C2CStatFitUIAxesRESET, fullfile(path, strcat(filename, 'RESETDist.jpg')), 'Resolution', 300);
                %%
                %% Save .fig
                fig1 = figure;
                copyobj(app.C2CNormWeightUIAxesSETParamA, fig1);
                savefig(fullfile(path, strcat(filename, 'SETParamA.fig')));
                
                fig2 = figure;
                copyobj(app.C2CNormWeightUIAxesSETParamB, fig2);
                savefig(fullfile(path, strcat(filename, 'SETParamB.fig')));
                
                fig3 = figure;
                copyobj(app.C2CNormWeightUIAxesRESETParamA, fig3);
                savefig(fullfile(path, strcat(filename, 'RESETParamA.fig')));
                
                fig4 = figure;
                copyobj(app.C2CNormWeightUIAxesRESETParamB, fig4);
                savefig(fullfile(path, strcat(filename, 'RESETParamB.fig')));
                
                close([fig1,fig2,fig3,fig4]);
                %clear fig1 fig2 fig3 fig4;
            else
                errordlg('C2C Struct not defined! Please reload the data');
            end
        end

        % Button pushed function: AddDatastoreFileButton
        function AddDatastoreFileButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select Input Datastore file', 'MNISTds.mat');
            
            fullpath = fullfile(path, file);
            
            app.DisturbancefilesUITable.Data{1} = fullpath;
        end

        % Button pushed function: RemoveDatastoreFileButton
        function RemoveDatastoreFileButtonPushed(app, event)
            app.DisturbancefilesUITable.Data{1} = '';
        end

        % Button pushed function: AddDiscretizationFileButton
        function AddDiscretizationFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select Discretization Struct file', 'DiscretizationStruct.mat');
            
            fullpath = fullfile(path, file);
            
            app.DisturbancefilesUITable.Data{2,1} = fullpath;
        end

        % Button pushed function: RemoveDiscretizationFileButton
        function RemoveDiscretizationFileButtonPushed(app, event)
            app.DisturbancefilesUITable.Data{2} = '';
        end

        % Button pushed function: AddD2DFileButton
        function AddD2DFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select D2D Struct file', 'D2DStruct.mat');
            
            fullpath = fullfile(path, file);
            
            app.DisturbancefilesUITable.Data{3,1} = fullpath;
        end

        % Button pushed function: RemoveD2DFileButton
        function RemoveD2DFileButtonPushed(app, event)
            app.DisturbancefilesUITable.Data{3} = '';
        end

        % Button pushed function: AddC2CFileButton
        function AddC2CFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select C2C Struct file', 'C2CStruct.mat');
            
            fullpath = fullfile(path, file);
            
            app.DisturbancefilesUITable.Data{4,1} = fullpath;
        end

        % Button pushed function: RemoveC2CFileButton
        function RemoveC2CFileButtonPushed(app, event)
            app.DisturbancefilesUITable.Data{4} = '';
        end

        % Button pushed function: AddNNFileButton
        function AddNNFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select NN file', 'nnet.mat');
            
            fullpath = fullfile(path, file);
            
            app.DisturbancefilesUITable.Data{5,1} = fullpath;
        end

        % Button pushed function: RemoveNNFileButton
        function RemoveNNFileButtonPushed(app, event)
            app.DisturbancefilesUITable.Data{5} = '';
        end

        % Button pushed function: AddTrainSessionButton
        function AddTrainSessionButtonPushed(app, event)
            %Layer Definition
            layerf_handle_str = strcat('@()', erase(join(string(app.NNLayersTextArea.Value)), '...'));
            
            % Training Options
            trainOptionsf_handle_str = strcat('@()', erase(join(string(app.NNOptionsTextArea.Value)), '...'));
            
            % NN File
            NNFile = app.DisturbancefilesUITable.Data{5,1};

            %Session Name
            newRow{1,1} = app.SessionNameNNDefinitionEditField.Value;
            %Training/Inference
            newRow{1,2} = 'Training';
            
            %InputDatastore_file
            if isfile(app.DisturbancefilesUITable.Data{1,1})
                newRow{1,3} = app.DisturbancefilesUITable.Data{1,1};
            else
                errordlg('Input Datastore file not defined. Please add Datastore file!');
                return;
            end
            
            %Validation Data
            newRow{1,4} = app.ValidationDataEditField.Value;
            %Number of runs
            newRow{1,5} = app.NumberofRunsEditField.Value;
            
            %Programming_Method
            newRow{1,6} = app.ProgrammingMethodDropDown.Value;
            
            if strcmp(NNFile, '') == true
                %Use Previous NN
                newRow{1,7} = app.UsePreviousNNCheckBox.Value;
                %Use Loaded NN
                newRow{1,8} = false;
                
            elseif strcmp(NNFile, '') == false && app.UsePreviousNNCheckBox.Value == true
                
                answer = questdlg('Loaded NN File and Use Previous NN both true. Which one do you want to use?', ...
                    'Loaded vs Previous NN?', ...
                    'Loaded NN', 'Previous NN', 'Cancel', 'Cancel');
                
                switch answer
                    case 'Loaded NN'
                        %Use Previous NN
                        newRow{1,7} = false;
                        %Use Loaded NN
                        newRow{1,8} = true;
                    case 'Previous NN'
                        %Use Previous NN
                        newRow{1,7} = true;
                        %Use Loaded NN
                        newRow{1,8} = false;
                    case 'Cancel'
                        return;
                end               
            else
                %Use Previous NN
                newRow{1,7} = false;
                %Use Loaded NN
                newRow{1,8} = true;
            end
            
            if newRow{1,8} == true
                %Loaded NN_File
                newRow{1,9} = NNFile;
            else
                newRow{1,9} = '';
            end

            %Discretize_file
            if isfile(app.DisturbancefilesUITable.Data{2,1}) || strcmp(app.DisturbancefilesUITable.Data{2,1}, '')
                newRow{1,10} = app.DisturbancefilesUITable.Data{2,1};
            else
                errordlg('Loaded Disturbance file is invalid. Reload or Remove file entry!');
                return;
            end
            %D2D_file
            if isfile(app.DisturbancefilesUITable.Data{3,1}) || strcmp(app.DisturbancefilesUITable.Data{3,1}, '')
                newRow{1,11} = app.DisturbancefilesUITable.Data{3,1};
            else
                errordlg('Loaded D2D file is invalid. Reload or Remove file entry!');
                return;
            end
            %C2C_file
            if isfile(app.DisturbancefilesUITable.Data{4,1}) || strcmp(app.DisturbancefilesUITable.Data{4,1}, '')
                newRow{1,12} = app.DisturbancefilesUITable.Data{4,1};
            else
                errordlg('Loaded C2C file is invalid. Reload or Remove file entry!')
            end
            
            % !!Important!! Pass the function handles instead of the variables to the script that
            % will handle training. This will avoid crashing before
            % entering the training script
            
            
            %NN Layers
            newRow{1,13} = char(layerf_handle_str);
            %NN Options
            newRow{1,14} = char(trainOptionsf_handle_str);
            
            %Plot Training Progress bool
            newRow{1,15} = app.TrainingProgressPlotCheckBox.Value;
            %Plot Weight & Gradient Histograms
            newRow{1,16} = app.WeightGradientHistogramsCheckBox.Value;
            %Plot GradCAM
            newRow{1,17} = app.GradCAMCheckBox.Value;
            %Plot Weight Heatmaps
            newRow{1,18} = app.WeightHeatmapsCheckBox.Value;
            %Plot Gradient Heatmaps
            newRow{1,19} = app.GradientHeatmapsCheckBox.Value;
            %Plot All run summary
            newRow{1,20} = app.AllRunSummaryPlotsCheckBox.Value;
            
            %Save Weights & Gradients
            newRow{1,21} = app.SaveWeightGradientDataCheckBox.Value;
            
            %Weight Range Rescaling Method
            newRow{1,22} = app.WeightRangeRescalingMethodButtonGroup.SelectedObject.Text;
            
            %Fixed Weight Range Limits
            newRow{1,23} = app.FixedLimitsEditField.Value;
            
            if ~isempty(app.NNSessionManagerTable.Data)
                if any(string(app.NNSessionManagerTable.Data(:,1)) == app.SessionNameNNDefinitionEditField.Value)
                    answer = questdlg('Session Name already in use. Overwrite session?', 'Name Warning', 'Yes', 'Cancel', 'Cancel');
                    switch answer
                        case 'Yes'
                            row_idx = find(string(app.NNSessionManagerTable.Data(:,1)) == app.SessionNameNNDefinitionEditField.Value);
                            app.NNSessionManagerTable.Data(row_idx, :) = newRow;
                        case 'Cancel'
                            return;
                    end
                else
                    app.NNSessionManagerTable.Data = [app.NNSessionManagerTable.Data ; newRow];
                end
            else
                app.NNSessionManagerTable.Data = newRow;
            end
            
        end

        % Button pushed function: AddInferenceSessionButton
        function AddInferenceSessionButtonPushed(app, event)
            %Layer Definition
            layerf_handle_str = strcat('@()', erase(join(string(app.NNLayersTextArea.Value)), '...'));
            
            % Training Options
            trainOptionsf_handle_str = strcat('@()', erase(join(string(app.NNOptionsTextArea.Value)), '...'));
            
            % NN File
            NNFile = app.DisturbancefilesUITable.Data{5,1};


            %Session Name
            newRow{1,1} = app.SessionNameNNDefinitionEditField.Value;
            %Training/Inference
            newRow{1,2} = 'Inference';
            
            %InputDatastore_file
            if isfile(app.DisturbancefilesUITable.Data{1,1})
                newRow{1,3} = app.DisturbancefilesUITable.Data{1,1};
            else
                errordlg('Input Datastore file not defined. Please add Datastore file!');
                return;
            end
            
            %Validation Data
            newRow{1,4} = app.ValidationDataEditField.Value;
            %Number of runs
            newRow{1,5} = app.NumberofRunsEditField.Value;
            
            %Programming_Method
            newRow{1,6} = app.ProgrammingMethodDropDown.Value;
            
            if strcmp(NNFile, '') == true
                %Use Previous NN
                newRow{1,7} = app.UsePreviousNNCheckBox.Value;
                %Use Loaded NN
                newRow{1,8} = false;
                
            elseif strcmp(NNFile, '') == false && app.UsePreviousNNCheckBox.Value == true
                
                answer = questdlg('Loaded NN File and Use Previous NN both true. Which one do you want to use?', ...
                    'Loaded vs Previous NN?', ...
                    'Loaded NN', 'Previous NN', 'Cancel', 'Cancel');
                
                switch answer
                    case 'Loaded NN'
                        %Use Previous NN
                        newRow{1,7} = false;
                        %Use Loaded NN
                        newRow{1,8} = true;
                    case 'Previous NN'
                        %Use Previous NN
                        newRow{1,7} = true;
                        %Use Loaded NN
                        newRow{1,8} = false;
                    case 'Cancel'
                        return;
                end               
            else
                %Use Previous NN
                newRow{1,7} = false;
                %Use Loaded NN
                newRow{1,8} = true;
            end
            
            if newRow{1,8} == true
                %Loaded NN_File
                newRow{1,9} = NNFile;
            else
                newRow{1,9} = '';
            end

            %Discretize_file
            if isfile(app.DisturbancefilesUITable.Data{2,1}) || strcmp(app.DisturbancefilesUITable.Data{2,1}, '')
                newRow{1,10} = app.DisturbancefilesUITable.Data{2,1};
            else
                errordlg('Loaded Disturbance file is invalid. Reload or Remove file entry!');
                return;
            end
            %D2D_file
            if isfile(app.DisturbancefilesUITable.Data{3,1}) || strcmp(app.DisturbancefilesUITable.Data{3,1}, '')
                newRow{1,11} = app.DisturbancefilesUITable.Data{3,1};
            else
                errordlg('Loaded D2D file is invalid. Reload or Remove file entry!');
                return;
            end
            %C2C_file
            if isfile(app.DisturbancefilesUITable.Data{4,1}) || strcmp(app.DisturbancefilesUITable.Data{4,1}, '')
                newRow{1,12} = app.DisturbancefilesUITable.Data{4,1};
            else
                errordlg('Loaded C2C file is invalid. Reload or Remove file entry!')
            end
            
            % !!Important!! Pass the function handles instead of the variables to the script that
            % will handle training. This will avoid crashing before
            % entering the training script
            
            
            %NN Layers
            newRow{1,13} = char(layerf_handle_str);
            %NN Options
            newRow{1,14} = char(trainOptionsf_handle_str);
            
            %Plot Training Progress bool
            newRow{1,15} = app.TrainingProgressPlotCheckBox.Value;
            %Plot Weight & Gradient Histograms
            newRow{1,16} = app.WeightGradientHistogramsCheckBox.Value;
            %Plot GradCAM
            newRow{1,17} = app.GradCAMCheckBox.Value;
            %Plot Weight Heatmaps
            newRow{1,18} = app.WeightHeatmapsCheckBox.Value;
            %Plot Gradient Heatmaps
            newRow{1,19} = app.GradientHeatmapsCheckBox.Value;
            %Plot All run summary
            newRow{1,20} = app.AllRunSummaryPlotsCheckBox.Value;
            
            %Save Weights & Gradients
            newRow{1,21} = app.SaveWeightGradientDataCheckBox.Value;
            
            %Weight Range Rescaling Method
            newRow{1,22} = app.WeightRangeRescalingMethodButtonGroup.SelectedObject.Text;
            
            %Fixed Weight Range Limits
            newRow{1,23} = app.FixedLimitsEditField.Value;
            
            if ~isempty(app.NNSessionManagerTable.Data)
                if any(string(app.NNSessionManagerTable.Data(:,1)) == app.SessionNameNNDefinitionEditField.Value)
                    answer = questdlg('Session Name already in use. Overwrite session?', 'Name Warning', 'Yes', 'Cancel', 'Cancel');
                    switch answer
                        case 'Yes'
                            row_idx = find(string(app.NNSessionManagerTable.Data(:,1)) == app.SessionNameNNDefinitionEditField.Value);
                            app.NNSessionManagerTable.Data(row_idx, :) = newRow;
                        case 'Cancel'
                            return;
                    end
                else
                    app.NNSessionManagerTable.Data = [app.NNSessionManagerTable.Data ; newRow];
                end
            else
                app.NNSessionManagerTable.Data = newRow;
            end
        end

        % Button pushed function: RemoveSessionButton
        function RemoveSessionButtonPushed(app, event)
            SessionName = app.SessionNameControlPanelEditField.Value;
            TableSessionNames = string(app.NNSessionManagerTable.Data(:,1));
            
            %Throw error message if no matching names are in table
            if ~any(TableSessionNames == SessionName)
                errordlg('Session name not found in table. Retype Session name');
                return;
            end
            
            row_idx = find(TableSessionNames == SessionName);
            
            if row_idx == 1
                %First Entry
                app.NNSessionManagerTable.Data = app.NNSessionManagerTable.Data(2:end,:);
            elseif row_idx == length(TableSessionNames)
                %Last Entry
                app.NNSessionManagerTable.Data(row_idx, :) = [];
            else
                %Other Entry
                table_beginning = app.NNSessionManagerTable.Data(1:row_idx-1, :);
                table_end = app.NNSessionManagerTable.Data(row_idx + 1 : end, :);
                
                app.NNSessionManagerTable.Data = [table_beginning ; table_end];
            end

        end

        % Button pushed function: MovetoTopButton
        function MovetoTopButtonPushed(app, event)
            SessionName = app.SessionNameControlPanelEditField.Value;
            TableSessionNames = string(app.NNSessionManagerTable.Data(:,1));
            
            %Throw error message if no matching names are in table
            if ~any(TableSessionNames == SessionName)
                errordlg('Session name not found in table. Retype Session name');
                return;
            end
            
            row_idx = find(TableSessionNames == SessionName);
            
            if row_idx == length(TableSessionNames)
                %Last Entry
                table_beginning = app.NNSessionManagerTable.Data(1:row_idx-1, :);
                
                app.NNSessionManagerTable.Data = [app.NNSessionManagerTable.Data(row_idx, :) ; table_beginning];
            elseif row_idx > 1 && row_idx < length(TableSessionNames)
                %Other Entry
                table_beginning = app.NNSessionManagerTable.Data(1:row_idx-1, :);
                table_end = app.NNSessionManagerTable.Data(row_idx + 1 : end, :);
                
                app.NNSessionManagerTable.Data = [app.NNSessionManagerTable.Data(row_idx, :) ; table_beginning ; table_end];
            end
        end

        % Button pushed function: MovetoBottomButton
        function MovetoBottomButtonPushed(app, event)
            SessionName = app.SessionNameControlPanelEditField.Value;
            TableSessionNames = string(app.NNSessionManagerTable.Data(:,1));
            
            %Throw error message if no matching names are in table
            if ~any(TableSessionNames == SessionName)
                errordlg('Session name not found in table. Retype Session name');
                return;
            end
            
            row_idx = find(TableSessionNames == SessionName);
            
            if row_idx == 1
                %First Entry
                table_end = app.NNSessionManagerTable.Data(row_idx + 1 : end, :);
                
                app.NNSessionManagerTable.Data = [table_end ; app.NNSessionManagerTable.Data(row_idx, :)];
            elseif row_idx > 1 && row_idx < length(TableSessionNames)
                %Other Entry
                table_beginning = app.NNSessionManagerTable.Data(1:row_idx-1, :);
                table_end = app.NNSessionManagerTable.Data(row_idx + 1 : end, :);
                
                app.NNSessionManagerTable.Data = [table_beginning ; table_end ; app.NNSessionManagerTable.Data(row_idx, :)];
            end
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            SessionName = app.SessionNameControlPanelEditField.Value;
            TableSessionNames = string(app.NNSessionManagerTable.Data(:,1));
            
            %Throw error message if no matching names are in table
            if ~any(TableSessionNames == SessionName)
                errordlg('Session name not found in table. Retype Session name');
                return;
            end
            
            row_idx = find(TableSessionNames == SessionName);
            
            if row_idx == 1
                %First Entry
                table_end = app.NNSessionManagerTable.Data(row_idx + 1 : end, :);
                
                app.NNSessionManagerTable.Data = [table_end(1, :); ...
                    app.NNSessionManagerTable.Data(row_idx, :); ...
                    table_end(2:end, :)];
                
            elseif row_idx > 1 && row_idx < length(TableSessionNames)
                %Other Entry
                table_beginning = app.NNSessionManagerTable.Data(1:row_idx-1, :);
                table_end = app.NNSessionManagerTable.Data(row_idx + 1 : end, :);
                
                app.NNSessionManagerTable.Data = [table_beginning; ...
                    table_end(1, :); ...
                    app.NNSessionManagerTable.Data(row_idx, :); ...
                    table_end(2:end, :)];
            end
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            SessionName = app.SessionNameControlPanelEditField.Value;
            TableSessionNames = string(app.NNSessionManagerTable.Data(:,1));
            
            %Throw error message if no matching names are in table
            if ~any(TableSessionNames == SessionName)
                errordlg('Session name not found in table. Retype Session name');
                return;
            end
            
            row_idx = find(TableSessionNames == SessionName);
            
            if row_idx == length(TableSessionNames)
                %Last Entry
                table_beginning = app.NNSessionManagerTable.Data(1 : row_idx-1, :);
                
                app.NNSessionManagerTable.Data = [table_beginning(1:end-1, :); ...
                    app.NNSessionManagerTable.Data(row_idx, :); ...
                    table_beginning(end, :)];
                
            elseif row_idx > 1 && row_idx < length(TableSessionNames)
                %Other Entry
                table_beginning = app.NNSessionManagerTable.Data(1:row_idx-1, :);
                table_end = app.NNSessionManagerTable.Data(row_idx + 1 : end, :);
                
                app.NNSessionManagerTable.Data = [table_beginning(1:end-1, :); ...
                    app.NNSessionManagerTable.Data(row_idx, :); ...
                    table_beginning(end, :); ...
                    table_end];
            end
        end

        % Button pushed function: SaveSessionButton
        function SaveSessionButtonPushed(app, event)
            SessionName = app.SessionNameControlPanelEditField.Value;
            TableSessionNames = string(app.NNSessionManagerTable.Data(:,1));
            
            %Throw error message if no matching names are in table
            if ~any(TableSessionNames == SessionName)
                errordlg('Session name not found in table. Retype Session name');
                return;
            end
            
            row_idx = find(TableSessionNames == SessionName);
            
            TableEntry = app.NNSessionManagerTable.Data(row_idx, :);
            
            [file, path] = uiputfile('*.mat', 'Save Session Entry', SessionName);
            
            save(fullfile(path, file), "TableEntry");
        end

        % Button pushed function: SaveSessionTableButton
        function SaveSessionTableButtonPushed(app, event)
            TableData = app.NNSessionManagerTable.Data;
            
            [file, path] = uiputfile('*.mat', 'Save Session Table', 'TableData.mat');
            
            save(fullfile(path, file), "TableData");
        end

        % Button pushed function: LoadSessionButton
        function LoadSessionButtonPushed(app, event)
            TableSessionNames = string(app.NNSessionManagerTable.Data(:,1));
            
            [file, path] = uigetfile('*.mat', 'Load Session Entry', 'TableEntry.mat');
            
            temp_var_list = who('-file', fullfile(path, file));
            
            if ismember('TableEntry', temp_var_list)
                load(fullfile(path, file), 'TableEntry');
                if any(TableSessionNames == string(TableEntry(:,1)))
                    answer = questdlg('Loaded Session Entry has the same Name as an entry on the table. Overwrite?', ...
                        'Overwrite?', ...
                        'Yes', 'No', 'No');
                    
                    switch answer
                        case 'Yes'
                            row_idx = find(TableSessionNames == string(TableEntry(:,1)));
                            
                            app.NNSessionManagerTable.Data(row_idx, :) = TableEntry;
                        case 'No'
                            return;
                    end
                else
                    app.NNSessionManagerTable.Data = [app.NNSessionManagerTable.Data ; TableEntry];
                end
            else
                errordlg('Loaded File must contain var named "TableEntry"');
                return
            end
        end

        % Button pushed function: LoadSessionTableButton
        function LoadSessionTableButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Load Session', 'TableData.mat');
            
            temp_var_list = who('-file', fullfile(path, file));
            
            if ismember('TableData', temp_var_list)
                load(fullfile(path, file), 'TableData');
                app.NNSessionManagerTable.Data = TableData;
            else
                errordlg('Loaded File must contain var named "TableData"');
                return
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            for i = 1 : size(app.NNSessionManagerTable.Data, 1)
                mainNNRun(app.NNSessionManagerTable.Data(i,:));
                
                TableData = app.NNSessionManagerTable.Data(i+1:end, :);
                save(fullfile('SessionManager', 'CheckpointTable.mat'), "TableData");
            end
            
            % Automatic Shut down
            if app.ShutdownCheckBox.Value == true
                if ispc == true
                    disp('System shuting down');
                    system('shutdown /s');
                elseif isunix == true
                    disp('Shutdown function not available');
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'Main Panel';
            app.MainTabGroup = uitabgroup(app.MainPanel);
            app.VariabilityAnalysisTab = uitab(app.MainTabGroup);
            app.VariabilityAnalysisTab.Title = 'Variability Analysis';
            app.VariabilityanalysisPanel = uipanel(app.VariabilityAnalysisTab);
            app.VariabilityanalysisPanel.Title = 'Variability analysis';
            app.MethodDropDownLabel = uilabel(app.VariabilityanalysisPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.VariabilityanalysisPanel);
            app.MethodDropDown.Items = {'Single Distribution', 'Multi Distribution', 'Jump Table'};
            app.MethodDropDown.Value = 'Single Distribution';
            app.VariabilityInputTabGroup = uitabgroup(app.VariabilityanalysisPanel);
            app.DiscretizationTab = uitab(app.VariabilityInputTabGroup);
            app.DiscretizationTab.Title = 'Discretization';
            app.DiscretizationCheckBox = uicheckbox(app.DiscretizationTab);
            app.DiscretizationCheckBox.Text = 'Discretization';
            app.ModelLabel = uilabel(app.DiscretizationTab);
            app.ModelLabel.Text = 'Model: y_LTP = B*(1-exp(-x/A))+GMin';
            app.ModelLabel2 = uilabel(app.DiscretizationTab);
            app.ModelLabel2.Text = 'y_LTD = B*(1-exp((x-PMax)/A))+GMax';
            app.DiscretizationModelRESETEditFieldLabel = uilabel(app.DiscretizationTab);
            app.DiscretizationModelRESETEditFieldLabel.Text = 'RESET Model';
            app.DiscretizationModelRESETEditField = uieditfield(app.DiscretizationTab, 'text');
            app.DiscretizationModelRESETEditField.Value = 'B*(1-exp((x-PMax)/A))+GMax';
            app.DiscretizationModelSETEditFieldLabel = uilabel(app.DiscretizationTab);
            app.DiscretizationModelSETEditFieldLabel.Text = 'SET Model';
            app.DiscretizationModelSETEditField = uieditfield(app.DiscretizationTab, 'text');
            app.DiscretizationModelSETEditField.Value = 'B*(1-exp(-x/A))+GMin';
            app.DiscretizationExportStructButton = uibutton(app.DiscretizationTab, 'push');
            app.DiscretizationExportStructButton.ButtonPushedFcn = createCallbackFcn(app, @DiscretizationExportStructButtonPushed, true);
            app.DiscretizationExportStructButton.Text = 'Export Discretization Struct';
            app.DiscretizationExportappOptionsCheckBox = uicheckbox(app.DiscretizationTab);
            app.DiscretizationExportappOptionsCheckBox.Text = 'Export app Options';
            app.DiscretizationExportappOptionsCheckBox.Value = true;
            app.DiscretizationFitHelp = uiimage(app.DiscretizationTab);
            app.DiscretizationFitHelp.ImageClickedFcn = createCallbackFcn(app, @DiscretizationFitHelpImageClicked, true);
            app.DiscretizationFitHelp.ImageSource = 'HelpIcon.png';
            app.D2DVariabilityTab = uitab(app.VariabilityInputTabGroup);
            app.D2DVariabilityTab.Title = 'D2D Variability';
            app.D2DVariabilityCheckBox = uicheckbox(app.D2DVariabilityTab);
            app.D2DVariabilityCheckBox.Text = 'D2D Variability';
            app.D2DDistributionDropDownLabel = uilabel(app.D2DVariabilityTab);
            app.D2DDistributionDropDownLabel.Text = 'Distribution';
            app.D2DDistributionDropDown = uidropdown(app.D2DVariabilityTab);
            app.D2DDistributionDropDown.Items = {'Normal', 'Exponential', 'ExtremeValue', 'HalfNormal', 'Lognormal', 'Logistic', 'Loglogistic', 'Rayleigh', 'Weibull'};
            app.D2DDistributionDropDown.ValueChangedFcn = createCallbackFcn(app, @D2DDistributionDropDownValueChanged, true);
            app.D2DDistributionDropDown.Value = 'Normal';
            app.D2DmuEditFieldLabel = uilabel(app.D2DVariabilityTab);
            app.D2DmuEditFieldLabel.Text = 'mu';
            app.D2DmuEditField = uieditfield(app.D2DVariabilityTab, 'numeric');
            app.D2DsigmaEditFieldLabel = uilabel(app.D2DVariabilityTab);
            app.D2DsigmaEditFieldLabel.Text = 'sigma';
            app.D2DsigmaEditField = uieditfield(app.D2DVariabilityTab, 'numeric');
            app.D2DsigmaEditField.Value = 1;
            app.D2DNoteLabel = uilabel(app.D2DVariabilityTab);
            app.D2DNoteLabel.Text = '*Note: D2D functionality is user-input only in this version';
            app.D2DExportStructButton = uibutton(app.D2DVariabilityTab, 'push');
            app.D2DExportStructButton.ButtonPushedFcn = createCallbackFcn(app, @D2DExportStructButtonPushed, true);
            app.D2DExportStructButton.Text = 'Export D2D Struct';
            app.D2DExportappOptionsCheckBox = uicheckbox(app.D2DVariabilityTab);
            app.D2DExportappOptionsCheckBox.Text = 'Export app Options';
            app.C2CVariabilityTab = uitab(app.VariabilityInputTabGroup);
            app.C2CVariabilityTab.Title = 'C2C Variability';
            app.C2CVariabilityCheckBox = uicheckbox(app.C2CVariabilityTab);
            app.C2CVariabilityCheckBox.Text = 'C2C Variability';
            app.C2CButtonGroup = uibuttongroup(app.C2CVariabilityTab);
            app.C2CButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @C2CButtonGroupSelectionChanged, true);
            app.C2CExtractfromdataButton = uiradiobutton(app.C2CButtonGroup);
            app.C2CExtractfromdataButton.Text = 'Extract from data';
            app.C2CExtractfromdataButton.Value = true;
            app.C2CUserinputButton = uiradiobutton(app.C2CButtonGroup);
            app.C2CUserinputButton.Text = 'User-input';
            app.C2CDistributionDropDownLabel = uilabel(app.C2CVariabilityTab);
            app.C2CDistributionDropDownLabel.Text = 'Distribution';
            app.C2CDistributionDropDown = uidropdown(app.C2CVariabilityTab);
            app.C2CDistributionDropDown.Items = {'Normal', 'Exponential', 'ExtremeValue', 'HalfNormal', 'Lognormal', 'Logistic', 'Loglogistic', 'Rayleigh', 'Weibull'};
            app.C2CDistributionDropDown.ValueChangedFcn = createCallbackFcn(app, @C2CDistributionDropDownValueChanged, true);
            app.C2CDistributionDropDown.Value = 'Normal';
            app.C2CmuSETEditFieldLabel = uilabel(app.C2CVariabilityTab);
            app.C2CmuSETEditFieldLabel.Visible = 'off';
            app.C2CmuSETEditFieldLabel.Text = 'mu SET';
            app.C2CmuSETEditField = uieditfield(app.C2CVariabilityTab, 'numeric');
            app.C2CmuSETEditField.Visible = 'off';
            app.C2CsigmaSETEditFieldLabel = uilabel(app.C2CVariabilityTab);
            app.C2CsigmaSETEditFieldLabel.Visible = 'off';
            app.C2CsigmaSETEditFieldLabel.Text = 'sigma SET';
            app.C2CsigmaSETEditField = uieditfield(app.C2CVariabilityTab, 'numeric');
            app.C2CsigmaSETEditField.Visible = 'off';
            app.C2CWriteVerifyCheckBox = uicheckbox(app.C2CVariabilityTab);
            app.C2CWriteVerifyCheckBox.Text = 'Write-Verify';
            app.C2CSpacingDropDownLabel = uilabel(app.C2CVariabilityTab);
            app.C2CSpacingDropDownLabel.Text = 'Spacing';
            app.C2CSpacingDropDown = uidropdown(app.C2CVariabilityTab);
            app.C2CSpacingDropDown.Items = {'Linear', 'Pulse-Number'};
            app.C2CSpacingDropDown.Value = 'Linear';
            app.C2CFitModelEditFieldLabel = uilabel(app.C2CVariabilityTab);
            app.C2CFitModelEditFieldLabel.Text = 'Fit Model';
            app.C2CFitModelEditField = uieditfield(app.C2CVariabilityTab, 'text');
            app.C2CFitModelEditField.Value = 'a*exp(-((x-b)/c)^2)+d*x+e';
            app.C2CFitNumberofPointsEditFieldLabel = uilabel(app.C2CVariabilityTab);
            app.C2CFitNumberofPointsEditFieldLabel.Text = 'Fit Number of Points';
            app.C2CFitNumberofPointsEditField = uieditfield(app.C2CVariabilityTab, 'numeric');
            app.C2CFitNumberofPointsEditField.Limits = [1 Inf];
            app.C2CFitNumberofPointsEditField.Value = 500;
            app.C2CFitModelHelpImage = uiimage(app.C2CVariabilityTab);
            app.C2CFitModelHelpImage.ImageClickedFcn = createCallbackFcn(app, @C2CFitModelHelpImageClicked, true);
            app.C2CFitModelHelpImage.ImageSource = 'HelpIcon.png';
            app.C2CExportStructButton = uibutton(app.C2CVariabilityTab, 'push');
            app.C2CExportStructButton.ButtonPushedFcn = createCallbackFcn(app, @C2CExportStructButtonPushed, true);
            app.C2CExportStructButton.Text = 'Export C2C Struct';
            app.C2CExportappOptionsCheckBox = uicheckbox(app.C2CVariabilityTab);
            app.C2CExportappOptionsCheckBox.Text = 'Export app Options';
            app.C2CExportappOptionsCheckBox.Value = true;
            app.C2CmuRESETEditFieldLabel = uilabel(app.C2CVariabilityTab);
            app.C2CmuRESETEditFieldLabel.Visible = 'off';
            app.C2CmuRESETEditFieldLabel.Text = 'mu RESET';
            app.C2CmuRESETEditField = uieditfield(app.C2CVariabilityTab, 'numeric');
            app.C2CmuRESETEditField.Visible = 'off';
            app.C2CsigmaRESETEditFieldLabel = uilabel(app.C2CVariabilityTab);
            app.C2CsigmaRESETEditFieldLabel.Visible = 'off';
            app.C2CsigmaRESETEditFieldLabel.Text = 'sigma RESET';
            app.C2CsigmaRESETEditField = uieditfield(app.C2CVariabilityTab, 'numeric');
            app.C2CsigmaRESETEditField.Visible = 'off';
            app.WeightConversionLabel = uilabel(app.VariabilityanalysisPanel);
            app.WeightConversionLabel.Text = 'Weight Conversion';
            app.WeightConversionDropDown = uidropdown(app.VariabilityanalysisPanel);
            app.WeightConversionDropDown.Items = {'NormG', 'DeltaG/G'};
            app.WeightConversionDropDown.Value = 'NormG';
            app.NormalizeDropDownLabel = uilabel(app.VariabilityanalysisPanel);
            app.NormalizeDropDownLabel.Text = 'Normalize';
            app.NormalizeDropDown = uidropdown(app.VariabilityanalysisPanel);
            app.NormalizeDropDown.Items = {'Individually', 'All Cycles'};
            app.NormalizeDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizeDropDownValueChanged, true);
            app.NormalizeDropDown.Value = 'All Cycles';
            app.NormUpperRefLimitDropDownLabel = uilabel(app.VariabilityanalysisPanel);
            app.NormUpperRefLimitDropDownLabel.Text = 'Upper Ref Limit';
            app.NormUpperRefLimitDropDown = uidropdown(app.VariabilityanalysisPanel);
            app.NormUpperRefLimitDropDown.Items = {'Min', 'Mean', 'Median', 'Max'};
            app.NormUpperRefLimitDropDown.Value = 'Mean';
            app.NormLowerRefLimitDropDownLabel = uilabel(app.VariabilityanalysisPanel);
            app.NormLowerRefLimitDropDownLabel.Text = 'Lower Ref Limit';
            app.NormLowerRefLimitDropDown = uidropdown(app.VariabilityanalysisPanel);
            app.NormLowerRefLimitDropDown.Items = {'Min', 'Mean', 'Median', 'Max'};
            app.NormLowerRefLimitDropDown.Value = 'Min';
            app.RefCycleDropDownLabel = uilabel(app.VariabilityanalysisPanel);
            app.RefCycleDropDownLabel.Text = 'RefCycle';
            app.RefCycleDropDown = uidropdown(app.VariabilityanalysisPanel);
            app.RefCycleDropDown.Items = {'Mean', 'From Data'};
            app.RefCycleDropDown.ValueChangedFcn = createCallbackFcn(app, @RefCycleDropDownValueChanged, true);
            app.RefCycleDropDown.Value = 'Mean';
            app.RefCycleEditFieldLabel = uilabel(app.VariabilityanalysisPanel);
            app.RefCycleEditFieldLabel.Visible = 'off';
            app.RefCycleEditFieldLabel.Text = 'Cycle #';
            app.RefCycleEditField = uieditfield(app.VariabilityanalysisPanel, 'numeric');
            app.RefCycleEditField.Limits = [0 Inf];
            app.RefCycleEditField.RoundFractionalValues = 'on';
            app.RefCycleEditField.Visible = 'off';
            app.RefCycleEditField.Value = 1;
            app.VariabilityPlotsPanel = uipanel(app.VariabilityAnalysisTab);
            app.VariabilityPlotsPanel.Title = 'Variability Plots';
            app.VariabilityPlotsTabGroup = uitabgroup(app.VariabilityPlotsPanel);
            app.RawDataTab = uitab(app.VariabilityPlotsTabGroup);
            app.RawDataTab.Title = 'Raw Data';
            app.RawDataUIAxes = uiaxes(app.RawDataTab);
            xlabel(app.RawDataUIAxes, 'Pulse #')
            ylabel(app.RawDataUIAxes, 'G (S)')
            zlabel(app.RawDataUIAxes, 'Z')
            app.RawDataPlotButton = uibutton(app.RawDataTab, 'push');
            app.RawDataPlotButton.ButtonPushedFcn = createCallbackFcn(app, @RawDataPlotButtonPushed, true);
            app.RawDataPlotButton.Visible = 'off';
            app.RawDataPlotButton.Text = 'Plot';
            app.ModelTab = uitab(app.VariabilityPlotsTabGroup);
            app.ModelTab.Title = 'Model';
            app.ModelUIAxes = uiaxes(app.ModelTab);
            xlabel(app.ModelUIAxes, 'Normalized Pulse #')
            ylabel(app.ModelUIAxes, 'Normalized G')
            zlabel(app.ModelUIAxes, 'Z')
            app.ModelUIAxes.ButtonDownFcn = createCallbackFcn(app, @ModelUIAxesButtonDown, true);
            app.ModelPlotButton = uibutton(app.ModelTab, 'push');
            app.ModelPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ModelPlotButtonPushed, true);
            app.ModelPlotButton.Visible = 'off';
            app.ModelPlotButton.Text = 'Plot';
            app.D2DTab = uitab(app.VariabilityPlotsTabGroup);
            app.D2DTab.Title = 'D2D';
            app.D2DTabGroup = uitabgroup(app.D2DTab);
            app.D2DStatFitTab = uitab(app.D2DTabGroup);
            app.D2DStatFitTab.Title = 'StatFit';
            app.D2DStatFitUIAxes = uiaxes(app.D2DStatFitTab);
            xlabel(app.D2DStatFitUIAxes, 'X')
            ylabel(app.D2DStatFitUIAxes, 'Y')
            zlabel(app.D2DStatFitUIAxes, 'Z')
            app.D2DStatFitPlotButton = uibutton(app.D2DStatFitTab, 'push');
            app.D2DStatFitPlotButton.ButtonPushedFcn = createCallbackFcn(app, @D2DStatFitPlotButtonPushed, true);
            app.D2DStatFitPlotButton.Visible = 'off';
            app.D2DStatFitPlotButton.Text = 'Plot';
            app.D2DNormWeightFitTab = uitab(app.D2DTabGroup);
            app.D2DNormWeightFitTab.Title = 'NormWeightFit';
            app.D2DNormWeightFitUIAxesParamA = uiaxes(app.D2DNormWeightFitTab);
            xlabel(app.D2DNormWeightFitUIAxesParamA, 'X')
            ylabel(app.D2DNormWeightFitUIAxesParamA, 'Y')
            zlabel(app.D2DNormWeightFitUIAxesParamA, 'Z')
            app.D2DNormWeightFitUIAxesParamB = uiaxes(app.D2DNormWeightFitTab);
            xlabel(app.D2DNormWeightFitUIAxesParamB, 'X')
            ylabel(app.D2DNormWeightFitUIAxesParamB, 'Y')
            zlabel(app.D2DNormWeightFitUIAxesParamB, 'Z')
            app.D2DNormWeightFitPlotButton = uibutton(app.D2DNormWeightFitTab, 'push');
            app.D2DNormWeightFitPlotButton.ButtonPushedFcn = createCallbackFcn(app, @D2DNormWeightFitPlotButtonPushed, true);
            app.D2DNormWeightFitPlotButton.Visible = 'off';
            app.D2DNormWeightFitPlotButton.Text = 'Plot';
            app.C2CTab = uitab(app.VariabilityPlotsTabGroup);
            app.C2CTab.Title = 'C2C';
            app.C2CTabGroup = uitabgroup(app.C2CTab);
            app.C2CStatFitTab = uitab(app.C2CTabGroup);
            app.C2CStatFitTab.Title = 'StatFit';
            app.C2CStatFitUIAxesSET = uiaxes(app.C2CStatFitTab);
            xlabel(app.C2CStatFitUIAxesSET, 'X')
            ylabel(app.C2CStatFitUIAxesSET, 'Y')
            zlabel(app.C2CStatFitUIAxesSET, 'Z')
            app.C2CStatFitUIAxesRESET = uiaxes(app.C2CStatFitTab);
            xlabel(app.C2CStatFitUIAxesRESET, 'X')
            ylabel(app.C2CStatFitUIAxesRESET, 'Y')
            zlabel(app.C2CStatFitUIAxesRESET, 'Z')
            app.C2CStatFitPlotButton = uibutton(app.C2CStatFitTab, 'push');
            app.C2CStatFitPlotButton.ButtonPushedFcn = createCallbackFcn(app, @C2CStatFitPlotButtonPushed, true);
            app.C2CStatFitPlotButton.Visible = 'off';
            app.C2CStatFitPlotButton.Text = 'Plot';
            app.C2CNormalizedWeightPlotValueEditFieldLabel = uilabel(app.C2CStatFitTab);
            app.C2CNormalizedWeightPlotValueEditFieldLabel.Text = 'Normalized Weight value';
            app.C2CNormalizedWeightPlotValueEditField = uieditfield(app.C2CStatFitTab, 'numeric');
            app.C2CNormalizedWeightPlotValueEditField.Limits = [0 1];
            app.C2CStatFitPlotDropDown = uidropdown(app.C2CStatFitTab);
            app.C2CStatFitPlotDropDown.Items = {'All', 'Single Value', 'User-Input'};
            app.C2CStatFitPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @C2CStatFitPlotDropDownValueChanged, true);
            app.C2CStatFitPlotDropDown.Value = 'Single Value';
            app.C2CNormWeightFitTab = uitab(app.C2CTabGroup);
            app.C2CNormWeightFitTab.Title = 'NormWeightFit';
            app.C2CNormWeightUIAxesSETParamA = uiaxes(app.C2CNormWeightFitTab);
            xlabel(app.C2CNormWeightUIAxesSETParamA, 'X')
            ylabel(app.C2CNormWeightUIAxesSETParamA, 'Y')
            zlabel(app.C2CNormWeightUIAxesSETParamA, 'Z')
            app.C2CNormWeightUIAxesSETParamA.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.C2CNormWeightUIAxesSETParamA.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.C2CNormWeightUIAxesSETParamA.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.C2CNormWeightUIAxesSETParamA.ButtonDownFcn = createCallbackFcn(app, @C2CNormWeightUIAxesSETParamAButtonDown, true);
            app.C2CNormWeightUIAxesRESETParamA = uiaxes(app.C2CNormWeightFitTab);
            xlabel(app.C2CNormWeightUIAxesRESETParamA, 'X')
            ylabel(app.C2CNormWeightUIAxesRESETParamA, 'Y')
            zlabel(app.C2CNormWeightUIAxesRESETParamA, 'Z')
            app.C2CNormWeightUIAxesRESETParamA.ButtonDownFcn = createCallbackFcn(app, @C2CNormWeightUIAxesRESETParamAButtonDown, true);
            app.C2CNormWeightUIAxesSETParamB = uiaxes(app.C2CNormWeightFitTab);
            xlabel(app.C2CNormWeightUIAxesSETParamB, 'X')
            ylabel(app.C2CNormWeightUIAxesSETParamB, 'Y')
            zlabel(app.C2CNormWeightUIAxesSETParamB, 'Z')
            app.C2CNormWeightUIAxesSETParamB.ButtonDownFcn = createCallbackFcn(app, @C2CNormWeightUIAxesSETParamBButtonDown, true);
            app.C2CNormWeightUIAxesRESETParamB = uiaxes(app.C2CNormWeightFitTab);
            xlabel(app.C2CNormWeightUIAxesRESETParamB, 'X')
            ylabel(app.C2CNormWeightUIAxesRESETParamB, 'Y')
            zlabel(app.C2CNormWeightUIAxesRESETParamB, 'Z')
            app.C2CNormWeightUIAxesRESETParamB.ButtonDownFcn = createCallbackFcn(app, @C2CNormWeightUIAxesRESETParamBButtonDown, true);
            app.C2CNormWeightPlotButton = uibutton(app.C2CNormWeightFitTab, 'push');
            app.C2CNormWeightPlotButton.ButtonPushedFcn = createCallbackFcn(app, @C2CNormWeightPlotButtonPushed, true);
            app.C2CNormWeightPlotButton.Visible = 'off';
            app.C2CNormWeightPlotButton.Text = 'Plot';
            app.PlotAllButton = uibutton(app.VariabilityPlotsPanel, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButtonPushed, true);
            app.PlotAllButton.Visible = 'off';
            app.PlotAllButton.Text = 'Plot All';
            app.RRAMDataFileEditFieldLabel = uilabel(app.VariabilityAnalysisTab);
            app.RRAMDataFileEditFieldLabel.Text = 'RRAM Data File';
            app.RRAMDataFileEditField = uieditfield(app.VariabilityAnalysisTab, 'text');
            app.RRAMDataFileEditField.Value = 'C:\Users\Pedro\OneDrive - Liverpool John Moores University\MATLAB\PedroSim\Device_Data_examples\Raw_data\aVMCO\LR\Data.mat';
            app.RRAMDataFileBrowseButton = uibutton(app.VariabilityAnalysisTab, 'push');
            app.RRAMDataFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @RRAMDataFileBrowseButtonPushed, true);
            app.RRAMDataFileBrowseButton.Icon = 'BrowseIcon(1).gif';
            app.RRAMDataFileBrowseButton.Text = '';
            app.RRAMDataFileLoadButton = uibutton(app.VariabilityAnalysisTab, 'push');
            app.RRAMDataFileLoadButton.ButtonPushedFcn = createCallbackFcn(app, @RRAMDataFileLoadButtonPushed, true);
            app.RRAMDataFileLoadButton.Text = 'Load';
            app.VReadEditFieldLabel = uilabel(app.VariabilityAnalysisTab);
            app.VReadEditFieldLabel.Text = 'VRead';
            app.VReadEditField = uieditfield(app.VariabilityAnalysisTab, 'numeric');
            app.VReadEditField.Value = 3;
            app.NNDefinitionTab = uitab(app.MainTabGroup);
            app.NNDefinitionTab.Title = 'NN Definition';
            app.NNDefinitionPanel = uipanel(app.NNDefinitionTab);
            app.NNDefinitionPanel.Title = 'NN Definition';
            app.AddTrainSessionButton = uibutton(app.NNDefinitionPanel, 'push');
            app.AddTrainSessionButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrainSessionButtonPushed, true);
            app.AddTrainSessionButton.Text = 'Add Train Session';
            app.AddInferenceSessionButton = uibutton(app.NNDefinitionPanel, 'push');
            app.AddInferenceSessionButton.ButtonPushedFcn = createCallbackFcn(app, @AddInferenceSessionButtonPushed, true);
            app.AddInferenceSessionButton.Text = 'Add Inference Session';
            app.NNDefinitionOptionsTabGroup = uitabgroup(app.NNDefinitionPanel);
            app.NNDefinitionOptionsTab1 = uitab(app.NNDefinitionOptionsTabGroup);
            app.NNDefinitionOptionsTab1.Title = 'Options 1';
            app.UsePreviousNNCheckBox = uicheckbox(app.NNDefinitionOptionsTab1);
            app.UsePreviousNNCheckBox.Text = 'Use Previous NN';
            app.Label = uilabel(app.NNDefinitionOptionsTab1);
            app.Label.Text = '*Note: Validation Data argument in trainingOptions cannot be used at this moment';
            app.NNLayersTextAreaLabel = uilabel(app.NNDefinitionOptionsTab1);
            app.NNLayersTextAreaLabel.Text = 'NN Layers:';
            app.NNLayersTextArea = uitextarea(app.NNDefinitionOptionsTab1);
            app.NNLayersTextArea.Value = {'[imageInputLayer([28,28,1], ''Name'', ''inputlayer'')'; '    '; '    fullyConnectedLayer(30, ''Name'', ''fc1'')'; '    reluLayer(''Name'', ''relu1'')'; ''; '    fullyConnectedLayer(10, ''Name'', ''fc2'')'; '    softmaxLayer(''Name'', ''softmax1'')];'};
            app.NNOptionsTextAreaLabel = uilabel(app.NNDefinitionOptionsTab1);
            app.NNOptionsTextAreaLabel.Text = 'NN Options:';
            app.NNOptionsTextArea = uitextarea(app.NNDefinitionOptionsTab1);
            app.NNOptionsTextArea.Value = {'trainingOptions(''sgdm'', ...'; '    ''CheckpointPath'', '''', ...'; '    ''DispatchInBackground'', true, ...'; '    ''ExecutionEnvironment'', ''auto'', ...'; '    ''GradientThreshold'', Inf, ...'; '    ''GradientThresholdMethod'', ''l2norm'', ...'; '    ''L2Regularization'', 0.0001, ...'; '    ''InitialLearnRate'', 0.001, ...'; '    ''LearnRateSchedule'',''none'', ...'; '    ''LearnRateDropFactor'',0.2, ...'; '    ''LearnRateDropPeriod'',10, ...'; '    ''MaxEpochs'',10, ...'; '    ''MiniBatchSize'',128, ...'; '    ''Momentum'', 0.9, ...'; '    ''Plots'',''training-progress'', ...'; '    ''ResetInputNormalization'', true, ...'; '    ''Shuffle'', ''once'', ...'; '    ''ValidationFrequency'', 50, ...'; '    ''ValidationPatience'', Inf, ...'; '    ''Verbose'', true);'};
            app.DisturbancefilesUITable = uitable(app.NNDefinitionOptionsTab1);
            app.DisturbancefilesUITable.ColumnName = {'Path'};
            app.DisturbancefilesUITable.RowName = {'Datastore'; 'Discretization'; 'D2D'; 'C2C'; 'NN'};
            app.DisturbancefilesUITableLabel = uilabel(app.NNDefinitionOptionsTab1);
            app.DisturbancefilesUITableLabel.Text = {'Disturbance'; 'files'};
            app.SessionNameNNDefinitionEditFieldLabel = uilabel(app.NNDefinitionOptionsTab1);
            app.SessionNameNNDefinitionEditFieldLabel.Text = 'Session Name';
            app.SessionNameNNDefinitionEditField = uieditfield(app.NNDefinitionOptionsTab1, 'text');
            app.SessionNameNNDefinitionEditField.Value = 'Session1';
            app.AddD2DFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.AddD2DFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddD2DFileButtonPushed, true);
            app.AddD2DFileButton.Text = 'Add D2D File';
            app.RemoveD2DFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.RemoveD2DFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveD2DFileButtonPushed, true);
            app.RemoveD2DFileButton.Text = 'Remove D2D File';
            app.AddC2CFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.AddC2CFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddC2CFileButtonPushed, true);
            app.AddC2CFileButton.Text = 'Add C2C File';
            app.RemoveC2CFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.RemoveC2CFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveC2CFileButtonPushed, true);
            app.RemoveC2CFileButton.Text = 'Remove C2C File';
            app.AddDiscretizationFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.AddDiscretizationFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddDiscretizationFileButtonPushed, true);
            app.AddDiscretizationFileButton.Text = 'Add Discretization File';
            app.RemoveDiscretizationFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.RemoveDiscretizationFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDiscretizationFileButtonPushed, true);
            app.RemoveDiscretizationFileButton.Text = 'Remove Discretization File';
            app.ProgrammingMethodDropDownLabel = uilabel(app.NNDefinitionOptionsTab1);
            app.ProgrammingMethodDropDownLabel.Text = 'Programming Method';
            app.ProgrammingMethodDropDown = uidropdown(app.NNDefinitionOptionsTab1);
            app.ProgrammingMethodDropDown.Items = {'Gradient-Based', 'Set-Only', 'Reset-Only', 'Selective'};
            app.ProgrammingMethodDropDown.Value = 'Gradient-Based';
            app.AddNNFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.AddNNFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddNNFileButtonPushed, true);
            app.AddNNFileButton.Text = 'Add NN File';
            app.RemoveNNFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.RemoveNNFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNNFileButtonPushed, true);
            app.RemoveNNFileButton.Text = 'Remove NN File';
            app.AddDatastoreFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.AddDatastoreFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddDatastoreFileButtonPushed, true);
            app.AddDatastoreFileButton.Text = 'Add Datastore File';
            app.RemoveDatastoreFileButton = uibutton(app.NNDefinitionOptionsTab1, 'push');
            app.RemoveDatastoreFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDatastoreFileButtonPushed, true);
            app.RemoveDatastoreFileButton.Text = 'Remove Datastore File';
            app.ValidationDataEditFieldLabel = uilabel(app.NNDefinitionOptionsTab1);
            app.ValidationDataEditFieldLabel.Text = 'Validation Data';
            app.ValidationDataEditField = uieditfield(app.NNDefinitionOptionsTab1, 'numeric');
            app.ValidationDataEditField.Limits = [0 Inf];
            app.ValidationDataEditField.Tooltip = {'Number of inputs to be used for validation. Fractional values will be taken as a percentage of the dataset, while values >= 1 will be taken as an absolute number'};
            app.ValidationDataEditField.Value = 0.15;
            app.NumberofRunsEditFieldLabel = uilabel(app.NNDefinitionOptionsTab1);
            app.NumberofRunsEditFieldLabel.Text = 'Number of Runs';
            app.NumberofRunsEditField = uieditfield(app.NNDefinitionOptionsTab1, 'numeric');
            app.NumberofRunsEditField.Limits = [1 Inf];
            app.NumberofRunsEditField.RoundFractionalValues = 'on';
            app.NumberofRunsEditField.Tooltip = {'Number of times to run the session. All runs will be saved in the end'};
            app.NumberofRunsEditField.Value = 1;
            app.NNDefinitionOptionsTab2 = uitab(app.NNDefinitionOptionsTabGroup);
            app.NNDefinitionOptionsTab2.Title = 'Options 2';
            app.NNDefinitionPlotOptions = uipanel(app.NNDefinitionOptionsTab2);
            app.NNDefinitionPlotOptions.Title = 'Plot Options';
            app.PlotEveryRunPanel = uipanel(app.NNDefinitionPlotOptions);
            app.PlotEveryRunPanel.Title = 'Plot Every Run';
            app.TrainingProgressPlotCheckBox = uicheckbox(app.PlotEveryRunPanel);
            app.TrainingProgressPlotCheckBox.Text = 'Training Progress Plot';
            app.TrainingProgressPlotCheckBox.Value = true;
            app.WeightGradientHistogramsCheckBox = uicheckbox(app.PlotEveryRunPanel);
            app.WeightGradientHistogramsCheckBox.Text = 'Weight & Gradient Histograms';
            app.WeightGradientHistogramsCheckBox.Value = true;
            app.GradCAMCheckBox = uicheckbox(app.PlotEveryRunPanel);
            app.GradCAMCheckBox.Text = 'GradCAM';
            app.GradCAMCheckBox.Value = true;
            app.WeightHeatmapsCheckBox = uicheckbox(app.PlotEveryRunPanel);
            app.WeightHeatmapsCheckBox.Text = 'Weight Heatmaps';
            app.WeightHeatmapsCheckBox.Value = true;
            app.WeightGradientHeatmapWarningLabel = uilabel(app.PlotEveryRunPanel);
            app.WeightGradientHeatmapWarningLabel.Text = 'Note: Weight & Gradient Heatmaps requires Weight & Gradient Data to be saved';
            app.GradientHeatmapsCheckBox = uicheckbox(app.PlotEveryRunPanel);
            app.GradientHeatmapsCheckBox.Text = 'Gradient Heatmaps';
            app.GradientHeatmapsCheckBox.Value = true;
            app.PlotatendofallrunsPanel = uipanel(app.NNDefinitionPlotOptions);
            app.PlotatendofallrunsPanel.Title = 'Plot at end of all runs';
            app.AllRunSummaryPlotsCheckBox = uicheckbox(app.PlotatendofallrunsPanel);
            app.AllRunSummaryPlotsCheckBox.Text = 'All Run Summary Plots';
            app.AllRunSummaryPlotsCheckBox.Value = true;
            app.WeightRangeRescalingPanel = uipanel(app.NNDefinitionOptionsTab2);
            app.WeightRangeRescalingPanel.Title = 'Weight Range Rescaling';
            app.WeightRangeRescalingMethodButtonGroup = uibuttongroup(app.WeightRangeRescalingPanel);
            app.WeightRangeRescalingMethodButtonGroup.Title = 'Weight Range Rescaling Method';
            app.DynamicRangeButton = uiradiobutton(app.WeightRangeRescalingMethodButtonGroup);
            app.DynamicRangeButton.Text = 'Dynamic Range';
            app.DynamicRangeButton.Value = true;
            app.FixedRangeButton = uiradiobutton(app.WeightRangeRescalingMethodButtonGroup);
            app.FixedRangeButton.Text = 'Fixed Range';
            app.FixedWeightLimitsWarningLabel = uilabel(app.WeightRangeRescalingPanel);
            app.FixedWeightLimitsWarningLabel.Text = 'Note: Fixed Weight Upper Limits should be writen as an array of values separated by '','' containing the limits for each weight layer. e.g.( 0.2, 0.5 )';
            app.FixedLimitsEditFieldLabel = uilabel(app.WeightRangeRescalingPanel);
            app.FixedLimitsEditFieldLabel.Text = 'Fixed Limits';
            app.FixedLimitsEditField = uieditfield(app.WeightRangeRescalingPanel, 'text');
            app.FixedLimitsEditField.Value = '0.2, 0.3';
            app.SaveWeightGradientDataCheckBox = uicheckbox(app.NNDefinitionOptionsTab2);
            app.SaveWeightGradientDataCheckBox.Text = 'Save Weight & Gradient Data';
            app.SaveWeightGradientDataCheckBox.Value = true;
            app.NNSessionManagerPanel = uipanel(app.NNDefinitionTab);
            app.NNSessionManagerPanel.Title = 'Session Manager';
            app.NNSessionManagerTable = uitable(app.NNSessionManagerPanel);
            app.NNSessionManagerTable.ColumnName = {'Session Name'; 'Training/Inference'; 'Input Datastore_file'; 'Validation Data'; 'Repeats'; 'Programming Method'; 'Use Previous NN'; 'Use Loaded NN'; 'Loaded NN_file'; 'Discretize_file'; 'D2D_file'; 'C2C_file'; 'NN Layers'; 'NN Options'; 'Training Progress Plot'; 'Weight & Gradient Histograms'; 'GradCAM'; 'Weight Heatmaps'; 'Gradient Heatmaps'; 'All Run Summary Plots'; 'Save Weight & Gradient Data'; 'Weight Range Rescaling Method'; 'Fixed Weight Range Limits'};
            app.NNSessionManagerTable.RowName = {};
            app.NNSessionManagerTable.ColumnEditable = true;
            app.SessionManagerControlPanel = uipanel(app.NNSessionManagerPanel);
            app.SessionManagerControlPanel.Title = 'SessionManagerControlPanel';
            app.RemoveSessionButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.RemoveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSessionButtonPushed, true);
            app.RemoveSessionButton.Text = 'Remove Session';
            app.SessionNameControlPanelEditFieldLabel = uilabel(app.SessionManagerControlPanel);
            app.SessionNameControlPanelEditFieldLabel.Text = 'Session Name';
            app.SessionNameControlPanelEditField = uieditfield(app.SessionManagerControlPanel, 'text');
            app.MoveUpButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.MovetoBottomButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.MovetoBottomButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoBottomButtonPushed, true);
            app.MovetoBottomButton.Text = 'Move to Bottom';
            app.MovetoTopButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.MovetoTopButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoTopButtonPushed, true);
            app.MovetoTopButton.Text = 'Move to Top';
            app.SaveSessionButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.SaveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionButtonPushed, true);
            app.SaveSessionButton.Text = 'Save Session';
            app.SaveSessionTableButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.SaveSessionTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionTableButtonPushed, true);
            app.SaveSessionTableButton.Text = 'Save Session Table';
            app.LoadSessionButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.LoadSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSessionButtonPushed, true);
            app.LoadSessionButton.Text = 'Load Session';
            app.LoadSessionTableButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.LoadSessionTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSessionTableButtonPushed, true);
            app.LoadSessionTableButton.Text = 'Load Session Table';
            app.RunButton = uibutton(app.SessionManagerControlPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
            app.ShutdownCheckBox = uicheckbox(app.SessionManagerControlPanel);
            app.ShutdownCheckBox.Text = 'Shut down when finished';
            app.LimitedPrecisionTab = uitab(app.MainTabGroup);
            app.LimitedPrecisionTab.Title = 'Limited Precision';
            app.LimitedPrecisionPanel = uipanel(app.LimitedPrecisionTab);
            app.LimitedPrecisionPanel.Title = 'Limited Precision';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PedroSimApp_M2021a

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end